classdef MemoryMonitorAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Title        matlab.ui.control.Label
        Subtitle     matlab.ui.control.Label
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
    end


    properties (Access = private)
        MemoryTimer        % Timer object
        PlotLine           % Line object
    end

    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            % Get available memory
            [~,sys] = memory;
    
            % Calculate percentage and 
            usedmemory = sys.PhysicalMemory.Total-sys.PhysicalMemory.Available;
            totalmemory = sys.PhysicalMemory.Total;
            
            % Update YData in plot
            y = (usedmemory/totalmemory) * 100;
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,1);
            ydata(1) = y;
            app.PlotLine.YData = ydata;
       end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure x- and y- axis
            app.UIAxes.XLim = [0 60];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 100];
            
            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.MemoryTimer.Running, 'off')
               start(app.MemoryTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the timer
            stop(app.MemoryTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.MemoryTimer);
            delete(app.MemoryTimer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memory Usage';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, '% Memory Used')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Percentage of Physical Memory Used';
            app.Subtitle = uilabel(app.UIFigure);
            app.Subtitle.Text = 'Measured Every Second';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemoryMonitorAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end