classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UltimusgluedispenserPanel    matlab.ui.container.Panel
        PressurebarEditFieldLabel    matlab.ui.control.Label
        PressurebarEditField         matlab.ui.control.NumericEditField
        VacuumH2OLabel               matlab.ui.control.Label
        VacuumH2OEditField           matlab.ui.control.NumericEditField
        TimemsEditFieldLabel         matlab.ui.control.Label
        TimemsEditField              matlab.ui.control.NumericEditField
        SetButton                    matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        CustomCommandsTextAreaLabel  matlab.ui.control.Label
        CustomCommandsTextArea       matlab.ui.control.TextArea
        StartDispensingButton        matlab.ui.control.StateButton
        DispensingModeButtonGroup    matlab.ui.container.ButtonGroup
        TimingButton                 matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        SetButton_2                  matlab.ui.control.Button
        SetButton_3                  matlab.ui.control.Button
        DefaultButton                matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        CallingApp; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dispenser)
           app.CallingApp = mainapp; 
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the button in main app
            app.CallingApp.SettingsButton.Enable = 'on';
            delete(app)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
        value = app.DispensingModeButtonGroup.SelectedObject
            switch value
                case 'Timing'
                    app.TimemsEditField.Enable = 0;
                case 'Continuous'
                    app.TimemsEditField.Enable = 0;
                otherwise
                    fprintf ("\nError de merluzo, el Dispensing Mode Button no rula!!!\n")
                    disp(value)
            end
        end

        % Callback function: PressurebarEditField, SetButton
        function PressurebarEditFieldValueChanged(app, event)
            app.SetButton.BackgroundColor = [0.96,0.96,0.96];
            value = app.PressurebarEditField.Value;
            error = 0;
            cmd = sprintf ('E6--01');      % Set dispenser units in BAR
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            value = value * 10000;
            cmd = sprintf('PS--%4d',value);    % Set dispenser pressure to 40 KPA = 0.4 BAR
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            if error == 0
                app.SetButton.BackgroundColor = 'g';
            else
                app.SetButton.BackgroundColor = 'r';
            end
        end

        % Callback function: SetButton_2, VacuumH2OEditField
        function VacuumH2OEditFieldValueChanged(app, event)
            app.SetButton_2.BackgroundColor = [0.96,0.96,0.96];
            value = app.VacuumH2OEditField.Value;
            error = 0;
            cmd = sprintf('E7--01');      % Set dispenser Vacuum unit "H2O
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            value = value * 10;
            cmd = sprintf('VS--%4d', value);    % Set dispenser vacuum to 0.12 KPA = 0.5 "H2O
            error = error + app.CallingApp.dispenser.SetUltimus(cmd);
            if error == 0
                app.SetButton_2.BackgroundColor = 'g';
            else
                app.SetButton_2.BackgroundColor = 'r';
                disp("Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UltimusgluedispenserPanel = uipanel(app.UIFigure);
            app.UltimusgluedispenserPanel.Title = 'Ultimus glue dispenser';
            app.PressurebarEditFieldLabel = uilabel(app.UltimusgluedispenserPanel);
            app.PressurebarEditFieldLabel.Text = 'Pressure (bar)';
            app.PressurebarEditField = uieditfield(app.UltimusgluedispenserPanel, 'numeric');
            app.PressurebarEditField.Limits = [0 8];
            app.PressurebarEditField.ValueDisplayFormat = '%5.2f';
            app.PressurebarEditField.ValueChangedFcn = createCallbackFcn(app, @PressurebarEditFieldValueChanged, true);
            app.VacuumH2OLabel = uilabel(app.UltimusgluedispenserPanel);
            app.VacuumH2OLabel.Text = 'Vacuum ("H2O)';
            app.VacuumH2OEditField = uieditfield(app.UltimusgluedispenserPanel, 'numeric');
            app.VacuumH2OEditField.ValueDisplayFormat = '%5.2f';
            app.VacuumH2OEditField.ValueChangedFcn = createCallbackFcn(app, @VacuumH2OEditFieldValueChanged, true);
            app.TimemsEditFieldLabel = uilabel(app.UltimusgluedispenserPanel);
            app.TimemsEditFieldLabel.Text = 'Time (ms)';
            app.TimemsEditField = uieditfield(app.UltimusgluedispenserPanel, 'numeric');
            app.TimemsEditField.Limits = [0 10000];
            app.TimemsEditField.ValueDisplayFormat = '%5.1f';
            app.SetButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @PressurebarEditFieldValueChanged, true);
            app.SetButton.Text = 'Set';
            app.CloseButton = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.CustomCommandsTextAreaLabel = uilabel(app.UltimusgluedispenserPanel);
            app.CustomCommandsTextAreaLabel.Text = 'Custom Commands';
            app.CustomCommandsTextArea = uitextarea(app.UltimusgluedispenserPanel);
            app.StartDispensingButton = uibutton(app.UltimusgluedispenserPanel, 'state');
            app.StartDispensingButton.Text = 'Start Dispensing';
            app.DispensingModeButtonGroup = uibuttongroup(app.UltimusgluedispenserPanel);
            app.DispensingModeButtonGroup.Title = 'Dispensing Mode';
            app.TimingButton = uiradiobutton(app.DispensingModeButtonGroup);
            app.TimingButton.Text = 'Timing';
            app.TimingButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.DispensingModeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.SetButton_2 = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @VacuumH2OEditFieldValueChanged, true);
            app.SetButton_2.Text = 'Set';
            app.SetButton_3 = uibutton(app.UltimusgluedispenserPanel, 'push');
            app.SetButton_3.Text = 'Set';
            app.DefaultButton = uibutton(app.UltimusgluedispenserPanel, 'state');
            app.DefaultButton.Text = 'Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end