classdef ModulePlacement_MATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModulePlacementUIFigure    matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveStateMenu              matlab.ui.container.Menu
        LoadStateMenu              matlab.ui.container.Menu
        CameraMenu                 matlab.ui.container.Menu
        SaveImageMenu              matlab.ui.container.Menu
        FormatMenu                 matlab.ui.container.Menu
        EmergencyStateMenu         matlab.ui.container.Menu
        SafeMoveMenu               matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        AccountMenu                matlab.ui.container.Menu
        LoginMenu                  matlab.ui.container.Menu
        PetalPic                   matlab.ui.control.UIAxes
        Label                      matlab.ui.control.Label
        MovementPanel              matlab.ui.container.Panel
        StopMotionButton           matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        AbsoluteTab                matlab.ui.container.Tab
        AbsStepSize_X              matlab.ui.control.NumericEditField
        AbsStepSize_Y              matlab.ui.control.NumericEditField
        AbsStepSize_Z              matlab.ui.control.NumericEditField
        AbsStepSize_U              matlab.ui.control.NumericEditField
        PositionmmLabel            matlab.ui.control.Label
        AbsButton_X                matlab.ui.control.Button
        AbsButton_Y                matlab.ui.control.Button
        AbsButton_Z                matlab.ui.control.Button
        AbsButton_U                matlab.ui.control.Button
        PositiondegLabel           matlab.ui.control.Label
        VelocitymmsEditFieldLabel  matlab.ui.control.Label
        VelocitymmsEditField       matlab.ui.control.NumericEditField
        IncrementalTab             matlab.ui.container.Tab
        IncStepSize_X              matlab.ui.control.NumericEditField
        IncStepSize_Y              matlab.ui.control.NumericEditField
        IncStepSize_Z              matlab.ui.control.NumericEditField
        IncStepSize_U              matlab.ui.control.NumericEditField
        StepSizemmLabel            matlab.ui.control.Label
        IncButton_Xp               matlab.ui.control.Button
        IncButton_Yp               matlab.ui.control.Button
        IncButton_Zp               matlab.ui.control.Button
        CWButton                   matlab.ui.control.Button
        IncButton_Xn               matlab.ui.control.Button
        IncButton_Yn               matlab.ui.control.Button
        IncButton_Zn               matlab.ui.control.Button
        CCWButton                  matlab.ui.control.Button
        StepSizedegLabel           matlab.ui.control.Label
        GraphicalTab               matlab.ui.container.Tab
        gantryViewButton           matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        ModuleLoadingTab           matlab.ui.container.Tab
        PetalButton                matlab.ui.control.Button
        ModuleDropDownLabel        matlab.ui.control.Label
        ModuleDropDown             matlab.ui.control.DropDown
        PlaceButton                matlab.ui.control.Button
        PetalLamp                  matlab.ui.control.Lamp
        GlueButton                 matlab.ui.control.Button
        CalibrateButton            matlab.ui.control.Button
        ModuleSurveyingTab         matlab.ui.container.Tab
        ModuleDropDown_2Label      matlab.ui.control.Label
        ModuleDropDown_2           matlab.ui.control.DropDown
        MoveButton                 matlab.ui.control.StateButton
        NextButton                 matlab.ui.control.StateButton
        ManualDAQTab               matlab.ui.container.Tab
        AXISButton                 matlab.ui.control.Button
        LED1Button                 matlab.ui.control.Button
        LED2Button                 matlab.ui.control.Button
        LED3Button                 matlab.ui.control.Button
        GantryVACButton            matlab.ui.control.Button
        ControlCheckBox            matlab.ui.control.CheckBox
        ChuckVACButton             matlab.ui.control.Button
        AxisPanel                  matlab.ui.container.Panel
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        ULabel                     matlab.ui.control.Label
        U_Home                     matlab.ui.control.Button
        Z_Home                     matlab.ui.control.Button
        Y_Home                     matlab.ui.control.Button
        X_Home                     matlab.ui.control.Button
        U_Enable                   matlab.ui.control.Button
        Z_Enable                   matlab.ui.control.Button
        Y_Enable                   matlab.ui.control.Button
        X_Enable                   matlab.ui.control.Button
        VelocityFeedbackPanel      matlab.ui.container.Panel
        TextArea_XV                matlab.ui.control.TextArea
        TextArea_YV                matlab.ui.control.TextArea
        TextArea_ZV                matlab.ui.control.TextArea
        TextArea_UV                matlab.ui.control.TextArea
        mmsLabel                   matlab.ui.control.Label
        mmsLabel_2                 matlab.ui.control.Label
        mmsLabel_3                 matlab.ui.control.Label
        degsLabel                  matlab.ui.control.Label
        PositionFeedbackPanel      matlab.ui.container.Panel
        TextArea_XP                matlab.ui.control.TextArea
        TextArea_YP                matlab.ui.control.TextArea
        TextArea_ZP                matlab.ui.control.TextArea
        TextArea_UP                matlab.ui.control.TextArea
        mmLabel                    matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        mmLabel_3                  matlab.ui.control.Label
        degLabel                   matlab.ui.control.Label
        HomeAllButton              matlab.ui.control.Button
        Switch                     matlab.ui.control.Switch
        PetalIDLabel               matlab.ui.control.Label
        PetalIDEditField           matlab.ui.control.EditField
        SaveButton                 matlab.ui.control.Button
        TabGroup3                  matlab.ui.container.TabGroup
        OutputTab                  matlab.ui.container.Tab
        OutputTextArea             matlab.ui.control.TextArea
    end

    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
    properties (Access = private)
        % Variables for DAQ
        s % signal
        num = 30; % number of control signals
        port % array of control signals
        daqFlag = 0; % Flag to record state of ni-DAQ
        
        % Port definitions for DAQ
        % (design circuit based on these values or change these values to match the circuit built)
        VAC1 = 1; % Vacuum port definitions:
        VAC2 = 2; % Festo valve terminal has 16 ports
        VAC3 = 3;
        % ...
        VAC16 = 16;
        
        LED1 = 17; % LED lights for shining under the inner frame
        LED2 = 18; % circular locators/fiducials
        LED3 = 19;
        AXIS = 20; % Camera through-lens light
        %---------------------------------------------------%
        % Gantry declarations
        gantryFlag = 0; % Flag to record state of gantry
        handle; % = A3200Connect;
        Y = 0;  % Y-Axis
        YY = 1; % YY-Axis
        X = 2;  % X-Axis
        Z = 3;  % Z-Axis
        U = 4;  % U-Axis
        allAxes = [0 1 2 3 4];
        task = 1;   % taskID
        
        % Flags not implemented yet
        homeFlag = zeros(1,4); 
        enableFlag = zeros(1,4);
        modulePlaced = zeros(2,9);
        
        velocity = 100;     % velocity when moving
        glueVelocity = 20;  % velocity when gluing
        accelTime = 5;      % time taken to reach set speed
        delay = 1;          % amount of seconds to wait after moving (buffer time for gantry movement)
        store = [0 0];      % pair of values used in calibration of camera offset during placement
        Lock = 0;           % flag for locking user control during certain movement/procedures
        %---------------------------------------------------%
        % Program Variable Declaration
        folder = ''; % Global variable for storing parent folder of all files used within code
        outputFile = '\Output\'; % Base filename used for accessing most files throughout code
        outputFileTmp = '\Output\GantryLog.txt'; % Overwritten filename for use throughout code while keeping above directory constant
        surveyResultsFile = 'surveyResults.xlsx'; % File for outputting survey results after surveying (used for plotting/statistics)
        
        imageOutDirectory = '\Output_Images\'; % Directory where all camera images are saved
        imageDirectory = '\Reference_Images\'; % Directory where all reference images are stored
        fiducialDirectory = '\Fiducials\'; % Directory where all fiducial files are stored
        
        lockFile = '\Control Panel.{21EC2020-3AEA-1069-A2DD-08002B30309D}'; % Folder path for when folder is locked
        unlockFile = '\AdminFolder'; % Folder path for when folder is open (editing)
        gantryFile = '\gantryValues.xlsx'; % File with hard-coded values of positions and module distance relationships
        surveyFile = 'surveyValues.xlsx'; % File with hard-coded values of where 'F' fiducials should be (relative to petal fiducials)
        glueFile = 'glueValues.xlsx'; % File with hard-coded distances of glue pattern (relative to petal fiducials)
        userFile = '\Users.xlsx'; % Locked file name of user/password information
        username = ''; % Placeholder for which user is logged in
        
        petalImg; % gloabal variable for editing the petal image during placement
        
        petalXYZ1;  % Variable for storing the coordinates of the INNER petal fiducial
        petalXYZ2;  % Variable for storing the coordinates of the OUTER petal fiducial
        petalAngle = 0;  % value of measured angle of petal (after measuring fiducial locations)
        
        sensorXYZ1; % Variable for storing the coordinates of the 'first' module fiducial
        sensorXYZ2; % Variable for storing the coordinates of the 'second' module fiducial
        sensorAngle;% Variable for storing the angle between the above locations
        
        toolXYZ;  % Variable for storing the coordinates of the pick-up tool(s)
        %---------------------------------------------------%
        % Offset lengths between glue tip and camera
        glueX = 231.1553;
        glueY = -0.1076;
        glueZ = -20.5000;
        
        % variable used for relating gantry, modules, and petal
        % (stored in external excel file - DO NOT MODIFY)
        endLength;
        endAngle;
        offLength;
        offAngle;
        holdAngle;
        petalEnd;
        
        camera; % x,y,z offset of camera location relative to gantry axis (in mm) - imported from excel
        cameraName = ''; % Name of camera used
        cameraID = 0; % Device ID for selecting which camera to use
        cameraAdaptor = ''; % Type of camera used
        cameraFormat = ''; % Resolution of camera used
        
        strMod1 = '';
        strMod2 = '';
        strOth = '';
        
        fid = ''; % Placeholder for which fiducial to fit - WILL CHANGE
        startAngle = 0; % Starting angle when searching for fiducials ('Fit' function)
        %---------------------------------------------------%
        % Camera variables
        gantryCam;
        corrVal = 0;
        threshold = 0.1;
        proceed = 0;
        refit = 0;
        cam;
        motionStop = 0;
        foc = 0;
        screen;
        scale = 50; % image is too big ot fit in figure, must reduce to '50%'
        tabView;
    end
    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
    methods (Access = private)
        % Function for homing the axes
        function Home(app, axis)
            v = 50;
            Moving(app,1);
            pause(0.1);
            homeDelay = 1;
            
            if (strcmp(axis,'X')) % Home just 'X'
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                stepX = abs(curX);
                totalTime = stepX/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.X);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, [1 0 0 0], totalTime + homeDelay);
                
                app.X_Home.Icon = 'Home.png';
                app.X_Home.BackgroundColor = [0.96 0.96 0.96];
            elseif (strcmp(axis,'Y'))
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                stepY = abs(curY);
                totalTime = stepY/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Y);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, [0 1 0 0], totalTime + homeDelay);
                
                app.Y_Home.Icon = 'Home.png';
                app.Y_Home.BackgroundColor = [0.96 0.96 0.96];
            elseif (strcmp(axis,'Z'))
                v = 25.4;
                
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                stepZ = abs(curZ);
                totalTime = stepZ/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Z);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, [0 0 1 0], totalTime + homeDelay);
                
                app.Z_Home.Icon = 'Home.png';
                app.Z_Home.BackgroundColor = [0.96 0.96 0.96];
            elseif (strcmp(axis,'U'))
                v = 36;
                
                curU = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                stepU = abs(curU);
                totalTime = stepU/v;
                
                % Home axis
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.U);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, [0 0 0 1], totalTime + homeDelay);
                
                app.U_Home.Icon = 'Home.png';
                app.U_Home.BackgroundColor = [0.96 0.96 0.96];
            else %(strcmp(axis,'all'))
                % Home in Z and U first when homing all axes
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                stepZ = abs(curZ);
                totalTimeZ = stepZ/25.4;
                
                curU = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                stepU = abs(curU);
                totalTimeU = stepU/36;
                
                if (totalTimeZ <= totalTimeU)
                    totalTime = totalTimeU;
                else
                    totalTime = totalTimeZ;
                end
                
                % Home Z and U
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Z);
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.U);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, [0 0 1 1], totalTime);
                % ------------------------------------------------------------ %
                % Home in X and Y
                Moving(app,1);
                pause(0.1);
                
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                stepX = abs(curX);
                totalTimeX = stepX/50;
                
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                stepY = abs(curY);
                totalTimeY = stepY/50;
                
                if (totalTimeX <= totalTimeY)
                    totalTime = totalTimeY;
                else
                    totalTime = totalTimeX;
                end
                
                % Home X and Y
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.X);
                A3200MotionAdvancedHomeAsync(app.handle,app.task,app.Y);
                
                % Allow data to be collected and movement to be stopped
                MoveTime(app, [1 1 0 0], totalTime + homeDelay);
                % ------------------------------------------------------------ %
                app.X_Home.Icon = 'Home.png';
                app.X_Home.BackgroundColor = [0.96 0.96 0.96];
                app.Y_Home.Icon = 'Home.png';
                app.Y_Home.BackgroundColor = [0.96 0.96 0.96];
                app.Z_Home.Icon = 'Home.png';
                app.Z_Home.BackgroundColor = [0.96 0.96 0.96];
                app.U_Home.Icon = 'Home.png';
                app.U_Home.BackgroundColor = [0.96 0.96 0.96];
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for enablinig axes
        function Enable(app,axis)
            % Check if the axis is already enabled - if it isn't, enable the axis and make the enable button 'green'
            % Otherwise, turn the brake on, disable the axis, then grey out the button
            if (strcmp(axis,'X')) % enable X-Axis
                if (app.enableFlag(1) == 0)
                    A3200MotionEnable(app.handle, app.task, app.X);
                    app.X_Enable.Icon = 'Enable.png';
                    app.X_Enable.BackgroundColor = [0.5 0.94 0.29];
                    app.enableFlag(1) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.X, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.X);
                    app.X_Enable.Icon = 'Disable.png';
                    app.X_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(1) = 0;
                end
            elseif (strcmp(axis,'Y')) % enable Y-Axis
                if (app.enableFlag(2) == 0)
                    A3200MotionEnable(app.handle, app.task, app.Y);
                    app.Y_Enable.Icon = 'Enable.png';
                    app.Y_Enable.BackgroundColor = [0.5 0.94 0.29];
                    app.enableFlag(2) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.Y, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.Y);
                    app.Y_Enable.Icon = 'Disable.png';
                    app.Y_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(2) = 0;
                end
            elseif (strcmp(axis,'Z')) % enable Z-Axis
                if (app.enableFlag(3) == 0)
                    A3200MotionEnable(app.handle, app.task, app.Z);
                    app.Z_Enable.Icon = 'Enable.png';
                    app.Z_Enable.BackgroundColor = [0.5 0.94 0.29];
                    app.enableFlag(3) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.Z, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.Z);
                    app.Z_Enable.Icon = 'Disable.png';
                    app.Z_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(3) = 0;
                end
            elseif (strcmp(axis,'U')) % enable U-Axis
                if (app.enableFlag(4) == 0)
                    A3200MotionEnable(app.handle, app.task, app.U);
                    app.U_Enable.Icon = 'Enable.png';
                    app.U_Enable.BackgroundColor = [0.5 0.94 0.29];
                    app.enableFlag(4) = 1;
                else
                    A3200IOBrake(app.handle, app.task, app.U, 1);
                    pause(0.5);
                    A3200MotionDisable(app.handle, app.task, app.U);
                    app.U_Enable.Icon = 'Disable.png';
                    app.U_Enable.BackgroundColor = [0.96 0.96 0.96];
                    app.enableFlag(4) = 0;
                end
            else % enable all axes -- only used at beginning of code (at start-up)
                A3200MotionEnable(app.handle, app.task, app.allAxes);
                
                app.X_Enable.Icon = 'Enable.png';
                app.X_Enable.BackgroundColor = [0.5 0.94 0.29];
                app.enableFlag(1) = 1;
                app.Y_Enable.Icon = 'Enable.png';
                app.Y_Enable.BackgroundColor = [0.5 0.94 0.29];
                app.enableFlag(2) = 1;
                app.Z_Enable.Icon = 'Enable.png';
                app.Z_Enable.BackgroundColor = [0.5 0.94 0.29];
                app.enableFlag(3) = 1;
                app.U_Enable.Icon = 'Enable.png';
                app.U_Enable.BackgroundColor = [0.5 0.94 0.29];
                app.enableFlag(4) = 1;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for connecting to camera
        function camOn(app, exp)
            if (app.cameraID ~= 0) % Check if camera exists before connecting to it
                imaqreset;
                app.cam = videoinput(app.cameraAdaptor,app.cameraID,app.cameraFormat,'ReturnedColorspace', 'grayscale');
                triggerconfig(app.cam, 'manual');
                app.cam.FramesPerTrigger = inf;
                app.cam.FrameGrabInterval = 1;
                src = getselectedsource(app.cam);
                src.ExposureMode = 'manual';
                src.Exposure = exp;
                start(app.cam);
                trigger(app.cam);
                
                pause(0.25);
                Collect(app,1);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for collecting position/velocity/camera data
        function Collect(app, flag)
            % Only collect data from gantry if the gantry could be connected
            if (app.gantryFlag)
                % Check if both the figure for the camera and the camera is connected before showing image
                if (ishandle(app.screen) && ~isempty(app.cam))
                    Image = flip(getsnapshot(app.cam),1);
                    imshow(Image, 'InitialMagnification',app.scale);
                end
                
                % 'flag' is used for if the gantry is moving or stopped
                if (~flag) % moving
                    x = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                    y = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                    z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                    u = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                    
                    xv = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.VelocityFeedback, 0);
                    yv = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.VelocityFeedback, 0);
                    zv = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.VelocityFeedback, 0);
                    uv = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.VelocityFeedback, 0);
                    
                    app.TextArea_XP.Value = num2str(x,'%0.4f');
                    app.TextArea_YP.Value = num2str(y,'%0.4f');
                    app.TextArea_ZP.Value = num2str(z,'%0.4f');
                    app.TextArea_UP.Value = num2str(u,'%0.4f');
                    
                    app.TextArea_XV.Value = num2str(xv,'%0.4f');
                    app.TextArea_YV.Value = num2str(yv,'%0.4f');
                    app.TextArea_ZV.Value = num2str(zv,'%0.4f');
                    app.TextArea_UV.Value = num2str(uv,'%0.4f');
                else % Stopped
                    pause(0.25);
                    
                    x = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                    y = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                    z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                    u = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                    
                    app.TextArea_XP.Value = num2str(x,'%0.4f');
                    app.TextArea_YP.Value = num2str(y,'%0.4f');
                    app.TextArea_ZP.Value = num2str(z,'%0.4f');
                    app.TextArea_UP.Value = num2str(u,'%0.4f');
                    
                    app.TextArea_XV.Value = '0.0000';
                    app.TextArea_YV.Value = '0.0000';
                    app.TextArea_ZV.Value = '0.0000';
                    app.TextArea_UV.Value = '0.0000';
                    
                    % Update top-view images once movement is stopped
                    imageOverwrite(app);
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for moving gantry in 'X' and 'Y' at specific velocity
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = MoveTo(app, x,y,v)
            % Check if default velocity is chosen (v = -1)
            if (v == -1)
                v = app.velocity;
            end
            
            % Gray out buttons and enable the emergency stop button while moving
            if (~app.Lock)
                Moving(app,1);
            end
            
            % Movement only in 'X'
            if (y == 1)
                % Calculate how long the movement will take ('totalTime')
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                stepX = abs(curX - x);
                if (stepX > v*app.accelTime)
                    totalTime = app.accelTime + stepX/v;
                else
                    totalTime = 2*sqrt(app.accelTime*stepX/v);
                end
                
                % Move axis to specified location
                A3200MotionMoveAbs(app.handle,app.task,app.X,x,v);
                
                % Allow data to be collected and movement to be stopped
                motion = MoveTime(app, [1 0 0 0], totalTime);
                
                % Movement only in 'Y'
            elseif (x == -1)
                % Calculate how long the movement will take ('totalTime')
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                stepY = abs(curY - y);
                if (stepY > v*app.accelTime)
                    totalTime = app.accelTime + stepY/v;
                else
                    totalTime = 2*sqrt(app.accelTime*stepY/v);
                end
                
                % Move axis to specified location
                A3200MotionMoveAbs(app.handle,app.task,app.Y,y,v);
                
                % Allow data to be collected and movement to be stopped
                motion = MoveTime(app, [0 1 0 0], totalTime);
                
                % Movement in both 'X' and 'Y'
            else
                % Check if default velocity is chosen (v = -1)
                if (v == -1)
                    v = app.velocity;
                end
                
                % Enable the emergency stop button while moving
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                dx = abs(curX - x);
                dy = abs(curY - y);
                hyp = sqrt(dx^2 + dy^2);
                velX = v*dx/hyp;
                velY = v*dy/hyp;
                
                % Calculate how long the movement will take ('totalTime')
                if (hyp > v*app.accelTime)
                    totalTime = app.accelTime + hyp/v;
                else
                    totalTime = 2*sqrt(app.accelTime*hyp/v);
                end
                
                % Move axis to specified location
                if (x ~= 0 || dx ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.X,x,velX);
                end
                if (y ~= 0 || dy ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.Y,y,velY);
                end
                
                % Allow data to be collected and movement to be stopped
                motion = MoveTime(app, [1 1 0 0], totalTime);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for moving gantry in 'Z' at specific velocity
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = MoveZ(app, z,v)
            if (v == -1)
                v = app.velocity;
            end
            
            % calculate how long the movement will take ('totalTime')
            curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            stepZ = abs(curZ - z);
            if (stepZ > v*app.accelTime)
                totalTime = app.accelTime + stepZ/v;
            else
                totalTime = 2*sqrt(app.accelTime*stepZ/v);
            end
            
            % move axis to specified location
            if (~app.Lock)
                Moving(app,1);
            end
            A3200MotionMoveAbs(app.handle,app.task,app.Z,z,v);
            
            % allow data to be collected and movement to be stopped
            motion = MoveTime(app, [0 0 1 0], totalTime);
        end
        %%-----------------------------------------------------------------------%%
        % Function for moving gantry in 'U' at 1/4 program velocity
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = MoveU(app, u)
            % calculate how long the movement will take ('totalTime')
            curU = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            stepU = abs(curU - u);
            if (stepU > app.velocity*app.accelTime/4)
                totalTime = app.accelTime + 4*stepU/app.velocity;
            else
                totalTime = 4*sqrt(app.accelTime*stepU/app.velocity);
            end
            
            % move axis to specified location
            if (~app.Lock)
                Moving(app,1);
            end
            A3200MotionMoveAbs(app.handle,app.task,app.U,u,(app.velocity/4));
            
            % allow data to be collected and movement to be stopped
            motion = MoveTime(app, [0 0 0 1], totalTime);
        end
        %%-----------------------------------------------------------------------%%
        % Function for stopping gantry movement (ie. emergency stop)
        function Abort(app,axis)
            % Stop current movement and move back a small amount before halting all movement
            %   - record the current location, speed up the acceleration rate to stop faster, then stop
            %     movement and move back to the recorded location followed by resetting the acceleration rate
            if (axis(1) && ~axis(2)) % X movement
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, 1);
                A3200MotionMoveAbs(app.handle, app.task, app.X, curX, 10);
            end
            if (axis(2) && ~axis(1)) % Y movement
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, 1);
                A3200MotionMoveAbs(app.handle, app.task, app.Y, curY, 10);
            end
            if (axis(1) && axis(2)) % X/Y movement
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, 1);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, 1);
                A3200MotionMoveAbs(app.handle, app.task, app.X, curX, 10);
                A3200MotionMoveAbs(app.handle, app.task, app.Y, curY, 10);
            end
            if (axis(3)) % Z movement (as well as when both Z and U are being homed)
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, 1);
                A3200MotionMoveAbs(app.handle, app.task, app.Z, curZ, 10);
            end
            if (axis(4) && ~axis(3)) % U movement
                curU = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, 1);
                A3200MotionMoveAbs(app.handle, app.task, app.U, curU, 2);
            end
            
            tic;
            while (toc < 4)
                % Wait for 4 seconds for gantry to stop moving/settle
            end
            
            % Change 'Home' button icons
            app.X_Home.Icon = 'Stop.png';
            app.X_Home.BackgroundColor = [1 0.1 0.15];
            app.Y_Home.Icon = 'Stop.png';
            app.Y_Home.BackgroundColor = [1 0.1 0.15];
            app.Z_Home.Icon = 'Stop.png';
            app.Z_Home.BackgroundColor = [1 0.1 0.15];
            app.U_Home.Icon = 'Stop.png';
            app.U_Home.BackgroundColor = [1 0.1 0.15];
            
            % Store information to log file about stopping gantry
            str = {'Motion stopped by user!'};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileTmp,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            % Reset acceleration time(s)
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
            A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
            
            % Halt all movement and reset emergency stop flag
            A3200MotionAbort(app.handle, app.allAxes);
            pause(0.5);
            app.motionStop = 0;
            
            % Ask user to re-home all axes before continuing
            msg = 'Re-home all axes?';
            title = 'Home Axes';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','No'},...
                'DefaultOption',1,'CancelOption',2);
            if (strcmp(selection,'Yes'))
                % Home all axes
                Home(app,'all');
            end
            
            % Reset movement buttons to 'Enabled' state
            Moving(app,0);
            app.Lock = 0;
            
            % Turn off "petal lamp" and enable the petal button
            app.PetalButton.Enable = 'on';
            app.PetalLamp.Color = [0.8 0.8 0.8];
            
            % Reload locations of modules, petal, and tools
            [~,~,data] = xlsread(app.gantryFile);
            
            app.petalAngle = 0;  % value of measured angle of petal (after measuring fiducial locations)
            
            str = 'Petal Coordinates';
            com = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
            row = find(com == 1); %Get Row number
            app.petalXYZ1 = cell2mat([data(row+1,1) data(row+1,2) data(row+1,3)]);
            app.petalXYZ2 = cell2mat([data(row+2,1) data(row+2,2) data(row+2,3)]);
            
            str = 'Camera';
            com = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
            row = find(com == 1); %Get Row number
            app.camera = cell2mat([data(row+1,1) data(row+1,2) data(row+1,3)]);
        end
        %%-----------------------------------------------------------------------%%
        % Function for finding the orientation of the petal before placing modules
        function findPetal(app)
            app.PetalButton.Enable = 'off';
            app.HomeAllButton.Enable = 'off';
            app.fid = '';
            % Lock interface from user
            app.Lock = 1;
            Moving(app,1);
            
            % Find first fiducial
            % Change exposure of camera
            delete(app.cam);
            camOn(app, -5);
            
            % Move to inner-fiducial coordinates
            str = {'Moving to first petal fiducial...'};
            writeOutput(app, str);
            motion = MoveTo(app, app.petalXYZ1(1), app.petalXYZ1(2), -1);
            if (~motion)
                return;
            end
            
            % Turn on backlight LED
            Light(app, 'LED1', 1);
            
            % Move down (to focus height)
            motion = MoveZ(app, app.petalXYZ1(3), -1);
            if (~motion)
                return;
            end
            
            % Find/fit circle fiducial
            find = fitCircle(app);
            % If circle could not be found, continuously run helper
            % function until user decides when circle is found:
            while (~find)
                find = circleFeedback(app);
            end
            
            % Turn off backlight LED
            Light(app, 'LED1', 0);
            
            % Store coordinates of 1st petal fiducial
            app.petalXYZ1(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ1(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ1(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            % Overwrite coordinates in excel file
            xlswrite(app.gantryFile, app.petalXYZ1, 'A2:C2');
            
            % Add the camera offset to the coordinates for future calculation
            app.petalXYZ1(1) = app.petalXYZ1(1) + app.camera(1);
            app.petalXYZ1(2) = app.petalXYZ1(2) + app.camera(2);
            %app.petalXYZ1(3) = app.petalXYZ1(3) + something;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Find second fiducial
            % Move to fiducial coordinates
            str = {'Fiducial found!','Moving to second petal fiducial...'};
            writeOutput(app, str);
            motion = MoveTo(app, app.petalXYZ2(1), app.petalXYZ2(2), -1);
            if (~motion)
                return;
            end
            
            % Turn on backlight LED
            Light(app, 'LED2', 1);
            
            % Move down
            motion = MoveZ(app, app.petalXYZ2(3), -1);
            if (~motion)
                return;
            end
            
            % Find/fit circle fiducial
            find = fitCircle(app);
            % If circle could not be found, continuously run helper
            % function until user decides when circle is found:
            while (~find)
                find = circleFeedback(app);
            end
            
            % Turn off backlight LED
            Light(app, 'LED2', 0);
            
            % Store coordinates of 2nd petal fiducial
            app.petalXYZ2(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ2(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.petalXYZ2(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            % Overwrite coordinates in excel file
            xlswrite(app.gantryFile, app.petalXYZ2, 'A3:C3');
            
            % Add the camera offset to the coordinates for next calculation
            app.petalXYZ2(1) = app.petalXYZ2(1) + app.camera(1);
            app.petalXYZ2(2) = app.petalXYZ2(2) + app.camera(2);
            %app.petalXYZ2(3) = app.petalXYZ2(3) + something;
            
            str = {'Fiducial found!','Petal orientation determined.'};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileTmp,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            % Calculate angle of petal
            app.petalAngle = rad2deg(atan2(app.petalXYZ2(2) - app.petalXYZ1(2), app.petalXYZ2(1) - app.petalXYZ1(1)));
            
            % Enable the required buttons for placing modules
            app.PlaceButton.Enable = 'on';
            app.GlueButton.Enable = 'on';
            app.CalibrateButton.Enable = 'on';
            app.ModuleDropDownLabel.Enable = 'on';
            app.ModuleDropDown.Enable = 'on';
            app.HomeAllButton.Enable = 'on';
            
            % Turn lamp next to 'Petal' button on (green)
            app.PetalLamp.Color = [0 1 0];
            
            % Un-lock interface from user
            app.Lock = 0;
            Moving(app,0);
        end
        %%-----------------------------------------------------------------------%%
        % Function for fitting petal fiducials (circles)
        function find = fitCircle(app)
            
            % Short pause to let gantry settle before getting snapshot
            pause(0.5);
            
            % Get a snapshot form camera
            img = flip(getsnapshot(app.cam),1);
            camImage = img;
            imWidth = size(camImage,2);
            imHeight = size(camImage,1);
            
            str = {'Finding fiducial...'};
            writeOutput(app, str);
            
            % Find any circles with built-in MATLAB function
            [centre,radius] = imfindcircles(camImage,[440 460], 'sensitivity',0.99, 'Method','twostage');
            
            % If no circles found:
            if (isempty(centre))
                % Try to move gantry to where any light is ('findLight' function)
                if (findLight(app,img))
                    % if light is found, try to find circle again
                    img = flip(getsnapshot(app.cam),1);
                    camImage = img;
                    imWidth = size(camImage,2);
                    imHeight = size(camImage,1);
                    
                    [centre,radius] = imfindcircles(camImage,[440 460], 'sensitivity',0.99, 'Method','twostage');
                    
                    if (isempty(centre))
                        % if circle is still not found, ask for user input
                        find = 0;
                    else
                        % if circle is found, break from loop
                        find = 1;
                    end
                else
                    % if no light was found, ask for user input
                    find = 0;
                end
                % If more than one circle is found
            elseif (size(radius,1) > 1)
                find = 0;
                % Otherwise circle was found
            else
                find = 1;
            end
            
            % If circle was found, overlay for user feedback
            if (find)
                % Once circle is found, overlay a circle and draw a line form the centre of
                % the camera to the centre of the circle and move along that line
                x = imWidth/2;
                y = imHeight/2;
                
                % Show image
                imshow(camImage,'InitialMagnification',app.scale);
                % Draw fit circle
                pos = [(centre(1)-radius) (centre(2)-radius) 2*radius 2*radius];
                rectangle('Position',pos,'Curvature',[1 1], 'EdgeColor','g', 'LineWidth',2);
                
                % Add in crosshairs (centre of camera)
                % ('x' line)
                xL = [(x-50) (x+50)];
                yL = [y y];
                line(xL, yL, 'Color', 'red');
                % ('y' line)
                xL = [x x];
                yL = [(y-50) (y+50)];
                line(xL, yL, 'Color', 'red');
                
                % Add camera-fiducial 'offset' line
                xL = [x centre(1)];
                yL = [y centre(2)];
                line(xL, yL, 'Color', 'green');
                drawnow;
                
                % Convert offset to um
                offsetX = (xL(2)-xL(1))/3;
                offsetY = -(yL(2)-yL(1))/3;
                % and move to location
                A3200MotionMoveInc(app.handle, app.task, app.X, offsetX/1000, 0.25);
                A3200MotionMoveInc(app.handle, app.task, app.Y, offsetY/1000, 0.25);
                A3200MotionWaitForMotionDone(app.handle, app.allAxes, A3200WaitOption.InPosition, 3000);
                pause(1);
                
                % Update camera and draw centred circle for user feedback
                Collect(app,1);
                pos = [(x-radius) (y-radius) 2*radius 2*radius];
                rectangle('Position',pos,'Curvature',[1 1], 'EdgeColor','g', 'LineWidth',2);
                
                % Ask user if fit is good
                msg = 'Was circle found correctly?';
                title = 'Circle found';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Yes','No'},...
                    'DefaultOption',1,'CancelOption',2);
                if (strcmp(selection,'No'))
                    % If 'No', change 'find' to zero and proceed to manual fit
                    find = 0;
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for moving towards light during 'fitCircle' function
        function light = findLight(app, img)
            k = 1;
            
            % Binarize image from camera
            camImage = imbinarize(img, 'global');
            imWidth = size(camImage,2);
            imHeight = size(camImage,1);
            lightX = zeros((imWidth*imHeight)/4,1);
            lightY = lightX;
            
            % Go through image finding where any light is
            pause(0.01);
            for i = 1:imWidth
                for j = 1:imHeight
                    if (camImage(j,i) > 0)
                        lightX(k) = i;
                        lightY(k) = j;
                        k = k + 1;
                    end
                end
            end
            
            % Find the location on the image with the most light
            x = ((sum(lightX)./sum(lightX~=0)) - (imWidth/2))/3000;
            y = -((sum(lightY)./sum(lightY~=0)) - (imHeight/2))/3000;
            
            % If there is no light, return '0', otherwise move towards light and return '1'
            if (isnan(x) == 1 || isnan(y) == 1)
                light = 0;
            else
                A3200MotionMoveInc(app.handle, app.task, app.X, x, 0.1);
                A3200MotionMoveInc(app.handle, app.task, app.Y, y, 0.1);
                A3200MotionWaitForMotionDone(app.handle, app.allAxes, A3200WaitOption.InPosition, -1);
                pause(0.25);
                Collect(app,1);
                light = 1;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for gluing (lines)
        function Glue(app, value)
            app.PlaceButton.Enable = 'off';
            app.GlueButton.Enable = 'off';
            app.ModuleDropDown.Enable = 'off';
            app.HomeAllButton.Enable = 'off';
            
            % Load in values of glue lines
            [~,~,data] = xlsread(app.glueFile);
            com = strcmpi(value, data(:,1));
            row = find(com == 1);
            numLines = size(data,1);
            numLines = numLines - 6;
            
            % Store lengths (radii of start/end points) and angles of glue lines in arrays
            TopR = cell2mat([data(row(1)+1,2) data(row(1)+1,3) data(row(1)+1,4) data(row(1)+1,5)]);
            TopA = cell2mat([data(row(1)+2,2) data(row(1)+2,3) data(row(1)+2,4) data(row(1)+2,5)]);
            BotR = cell2mat([data(row(1)+3,2) data(row(1)+3,3) data(row(1)+3,4) data(row(1)+3,5)]);
            BotA = cell2mat([data(row(1)+4,2) data(row(1)+4,3) data(row(1)+4,4) data(row(1)+4,5)]);
            
            % Store line distances and calculate movement times
            distanceMatrix = zeros(numLines,5);
            glueTime = zeros(1,7);
            glueFlag = 1;
            
            % Change acceleration ramp time based on glue velocity
            accelT = 10/app.glueVelocity;
            
            for i = 1:numLines
                for j = 1:5
                    distanceMatrix(i,j) = cell2mat(data(row(1)+5+i,j));
                end
            end
            
            for i = 1:numLines
                if mod(i,2) ~= 0
                    Radius1 = TopR(1)*i^3 + TopR(2)*i^2 + TopR(3)*i + TopR(4);
                    Radius2 = BotR(1)*i^3 + BotR(2)*i^2 + BotR(3)*i + BotR(4);
                    Angle1 = TopA(1)*i^3 + TopA(2)*i^2 + TopA(3)*i + TopA(4);
                    Angle2 = BotA(1)*i^3 + BotA(2)*i^2 + BotA(3)*i + BotA(4);
                    
                    glueTime(1) = accelT;
                    for j = 1:5
                        glueTime(j+1) = glueTime(j) + distanceMatrix(i,j)/app.glueVelocity;
                    end
                    glueTime(7) = glueTime(6) + accelT;
                else
                    Radius2 = TopR(1)*i^3 + TopR(2)*i^2 + TopR(3)*i + TopR(4);
                    Radius1 = BotR(1)*i^3 + BotR(2)*i^2 + BotR(3)*i + BotR(4);
                    Angle2 = TopA(1)*i^3 + TopA(2)*i^2 + TopA(3)*i + TopA(4);
                    Angle1 = BotA(1)*i^3 + BotA(2)*i^2 + BotA(3)*i + BotA(4);
                    
                    glueTime(1) = accelT;
                    for j = 1:5
                        glueTime(j+1) = glueTime(j) + distanceMatrix(i,(6-j))/app.glueVelocity;
                    end
                    glueTime(7) = glueTime(6) + accelT;
                end
                
                startX = app.petalXYZ1(1) + Radius1*cos(deg2rad(Angle1 + app.petalAngle)) + app.glueX - app.camera(1);
                startY = app.petalXYZ1(2) + Radius1*sin(deg2rad(Angle1 + app.petalAngle)) + app.glueY - app.camera(2);
                
                endX = app.petalXYZ1(1) + Radius2*cos(deg2rad(Angle2 + app.petalAngle)) + app.glueX - app.camera(1);
                endY = app.petalXYZ1(2) + Radius2*sin(deg2rad(Angle2 + app.petalAngle)) + app.glueY - app.camera(2);
                
                % Chenge lamp over module on petal to orange to represent gluing is in progress
                PetLamp(app,app.ModuleDropDown.Value, [1 0.5 0]);
                
                % Move to startpoint for gluing
                pause(0.5);
                if i == 1
                    motion = MoveTo(app, startX, startY, -1);
                    if (~motion)
                        return;
                    end
                    pause(0.5);
                    
                    % Slowly move tip close to petal surface
                    motion = MoveZ(app, app.petalXYZ1(3) + app.glueZ + 2.5, -1);
                    if (~motion)
                        return;
                    end
                    
                    motion = MoveZ(app, app.petalXYZ1(3) + app.glueZ, 1);
                    if (~motion)
                        return;
                    end
                    
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, accelT);
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, accelT);
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, accelT);
                    A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, accelT);
                else
                    motion = MoveTo(app, startX, startY, app.glueVelocity);
                    if (~motion)
                        return;
                    end
                    pause(0.5);
                end
                
                % Move and glue!
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                dx = abs(curX - endX);
                dy = abs(curY - endY);
                hyp = sqrt(dx^2+dy^2);
                velX = app.glueVelocity*dx/hyp;
                velY = app.glueVelocity*dy/hyp;
                
                tic;
                if (endX ~= 0 || dx ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.X,endX,velX);
                end
                if (endY ~= 0 || dy ~= 0)
                    A3200MotionMoveAbs(app.handle,app.task,app.Y,endY,velY);
                end
                
                app.StopMotionButton.Enable = 'on';
                % Allow data to be collected and movement to be stopped during gluing
                while (toc < glueTime(7) + 1)
                    elapsed = toc;
                    
                    if (elapsed > glueTime(1) && elapsed < glueTime(2))
                        if (glueFlag == 0)
                            app.port(21) = 1;
                            outputSingleScan(app.s,app.port);
                            pause(0.001);
                            glueFlag = 1;
                        end
                    elseif (elapsed > glueTime(3) && elapsed < glueTime(4))
                        if (glueFlag == 0)
                            app.port(21) = 1;
                            outputSingleScan(app.s,app.port);
                            pause(0.001);
                            glueFlag = 1;
                        end
                    elseif (elapsed > glueTime(5) && elapsed < glueTime(6))
                        if (glueFlag == 0)
                            app.port(21) = 1;
                            outputSingleScan(app.s,app.port);
                            pause(0.001);
                            glueFlag = 1;
                        end
                    elseif ((elapsed > glueTime(2) && elapsed < glueTime(3)) || (elapsed > glueTime(4) && elapsed < glueTime(5)) || (elapsed > glueTime(6) && elapsed < glueTime(7)))
                        if (glueFlag == 1)
                            app.port(21) = 0;
                            outputSingleScan(app.s,app.port);
                            pause(0.001);
                            glueFlag = 0;
                        end
                    else
                        % Check for 'Stop' button press
                        if (app.motionStop == 1)
                            app.port(21) = 0;
                            outputSingleScan(app.s,app.port);
                            Abort(app, 1,1,0,0);
                            Collect(app,1);
                            pause(0.001);
                            return;
                        end
                        pause(0.0001);
                    end
                    Collect(app,0);
                    pause(0.0001);
                end
                Collect(app,1);
                pause(0.001);
                app.StopMotionButton.Enable = 'off';
                
                % Reset acceleration ramp times
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
                
                str = sprintf('%s module area glued.',app.ModuleDropDown.Value);
                writeOutput(app, str);
                % Draw line in output
                fID = fopen(app.outputFileTmp,'at');
                fprintf(fID, '------------------------------------------------\n\n');
                fclose(fID);
                printOutput(app);
                
                app.HomeAllButton.Enable = 'on';
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for placing selected module from dropdown menu
        % *** CHECK Z-HEIGHT OF PICK-UP TOOL BEFORE RUNNING
        %     USE MOTION COMPOSER TO FIND IT FIRST AND CHANGE Z-HEIGHT IN LINE 1331
        %     (Heights of petal/modules adjust according to focus)
        function Place(app, cal)
            % Message box for making sure there is a module prepared
            msg = 'Is module on jig and vacuum available?';
            title = 'Confirm Module';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  (selection == 0)
                % if user selects 'cancel', return from function
                return;
            end
            
            if (~strcmp(cal,'calibrate'))
                % Update petal image
                PetalUpdate(app,app.ModuleDropDown.Value, 0);
            end
            app.PlaceButton.Enable = 'off';
            app.GlueButton.Enable = 'off';
            app.CalibrateButton.Enable = 'off';
            app.ModuleDropDown.Enable = 'off';
            app.HomeAllButton.Enable = 'off';
            
            % Load in module data for placing
            Search(app,app.ModuleDropDown.Value);
            
            % Lock interface from user
            app.Lock = 1;
            Moving(app,1);
            
            app.store = zeros(3,1);
            endXYZ = zeros(3,1);
            
            % Change exposure of camera before placing
            delete(app.cam);
            camOn(app, -2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (strcmp(cal,'calibrate')) % If 'calibrate' was selected:
                str = {'Module placement begun!','Calibration selected.','Moving to first module fiducial...'};
            else
                str = sprintf('%s module selected.',app.ModuleDropDown.Value);
                str = {'Module placement begun!',str,'Moving to first module fiducial...'};
            end
            writeOutput(app, str);
            % Move to first (lower-left) 'F' fiducial on sensor
            motion = MoveTo(app, app.sensorXYZ1(1), app.sensorXYZ1(2), -1);
            if (~motion)
                return;
            end
            
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Move down
            motion = MoveZ(app, app.sensorXYZ1(3), -1);
            if (~motion)
                return;
            end
            
            str = {'Finding fiducial...'};
            writeOutput(app, str);
            % Fit 1st 'F'
            app.fid = 'F';
            app.startAngle = -3;
            fit(app, app.startAngle);
            
            % Store adjusted coordinates of 'F' and overwrite old values
            app.sensorXYZ1(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ1(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ1(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            xlswrite(app.gantryFile, app.sensorXYZ1, app.strMod1);
            
            app.sensorXYZ1(1) = app.sensorXYZ1(1) + app.camera(1);
            app.sensorXYZ1(2) = app.sensorXYZ1(2) + app.camera(2);
            %app.sensorXYZ1(3) = app.sensorXYZ1(3) + something;
            pause(0.5);
            
            str = {'Fiducial found!','Moving to second module fiducial...'};
            writeOutput(app, str);
            % Move to second (upper-right) 'F' fiducial on sensor
            motion = MoveTo(app, app.sensorXYZ2(1), app.sensorXYZ2(2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, app.sensorXYZ2(3), -1);
            if (~motion)
                return;
            end
            
            str = {'Finding fiducial...'};
            writeOutput(app, str);
            % Fit 2nd 'F'
            app.startAngle = -11;
            fit(app, app.startAngle);
            
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Store adjusted coordinates of 'F' and overwrite old values
            app.sensorXYZ2(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ2(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            app.sensorXYZ2(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            
            xlswrite(app.gantryFile, app.sensorXYZ2, app.strMod2);
            
            app.sensorXYZ2(1) = app.sensorXYZ2(1) + app.camera(1);
            app.sensorXYZ2(2) = app.sensorXYZ2(2) + app.camera(2);
            %app.sensorXYZ2(3) = app.sensorXYZ2(3) + something;
            pause(0.5);
            
            str = {'Fiducial found!','Module orientation determined.','Moving to pick-up tool...'};
            writeOutput(app, str);
            app.sensorAngle = rad2deg(atan2(app.sensorXYZ2(2) - app.sensorXYZ1(2), app.sensorXYZ2(1) - app.sensorXYZ1(1)));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Move to correct pick-up tool
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            motion = MoveTo(app, app.toolXYZ(1), app.toolXYZ(2), -1);
            if (~motion)
                return;
            end
            motion = MoveU(app, 45);
            if (~motion)
                return;
            end
            motion = MoveZ(app, -128.5000, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, -130.5100, 0.75);
            if (~motion)
                return;
            end
            
            % Store coordinates of bridge (wait 3 seconds to let rotation axis settle)
            pause(3);
            tempX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            tempY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            tempZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            tempU = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            
            % Turn on gantry vacuum
            Vacuum(app, 'gantry', 1);
            pause(0.5);
            
            str = {'Pick-up tool obtained.','Moving to module...'};
            writeOutput(app, str);
            % Lift pick-up tool up
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Calculate x,y coordinates of sensor location
            x = app.sensorXYZ2(1) + app.offLength*cos(deg2rad(180 + app.sensorAngle - app.offAngle));
            y = app.sensorXYZ2(2) + app.offLength*sin(deg2rad(180 + app.sensorAngle - app.offAngle));
            
            % Move to correct location over sensor
            motion = MoveTo(app, x, y, -1);
            if (~motion)
                return;
            end
            motion = MoveU(app, -(app.sensorAngle + app.holdAngle - 135));
            if (~motion)
                return;
            end
            
            str = {'Move complete.','Finding module...'};
            writeOutput(app, str);
            motion = MoveZ(app, (app.sensorXYZ1(3) - 16.5), -1); % module surface is ~-20.7884 mm from focus
            if (~motion)
                return;
            end
            
            % "Force sensor"
            touchDown(app, 0.018, 0.2);
            pause(3); % wait for rotation axis to settle
            z = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            app.store(1) = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            pause(0.5);
            
            str = {'Module found.','Readjusting gantry head...'};
            writeOutput(app, str);
            
            % Turn off gantry vacuum
            Vacuum(app, 'gantry', 0);
            pause(0.5);
            
            % Lift up and rotate gantry-vacuum over holes
            motion = MoveZ(app, (z + 5), 2);
            if (~motion)
                return;
            end
            motion = MoveU(app, -(app.sensorAngle + app.holdAngle - 90));
            if (~motion)
                return;
            end
            motion = MoveZ(app, (z + 2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, z, 1);
            if (~motion)
                return;
            end
            
            % Turn on gantry vacuum; off chuck vacuum
            Vacuum(app, 'gantry', 1);
            %Vacuum(app, 'chuck',0);
            pause(0.5);
            
            str = {'Module obtained.','Moving to petal...'};
            writeOutput(app, str);
            % Lift sensor up
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            
            % Move over petal and place sensor
            % Calculate x,y coordinates of sensor location
            x = app.petalXYZ1(1) + app.endLength*cos(deg2rad(app.petalAngle + app.endAngle));
            y = app.petalXYZ1(2) + app.endLength*sin(deg2rad(app.petalAngle + app.endAngle));
            
            % Move to correct location over petal
            motion = MoveU(app, -(app.petalAngle + app.petalEnd - 90));% - 0.006));
            if (~motion)
                return;
            end
            motion = MoveTo(app, x, 1, -1);
            if (~motion)
                return;
            end
            motion = MoveTo(app, -1, y, -1);
            if (~motion)
                return;
            end
            
            str = {'Move complete.','Finding petal...'};
            writeOutput(app, str);
            motion = MoveZ(app, (app.petalXYZ2(3) - 14), -1); % petal surface is ~-17.5598 mm from focus
            if (~motion)
                return;
            end
            
            % "Force sensor"
            touchDown(app, 0.016, 0.2);
            pause(1);
            
            % Once petal has been found, slowly move down 50 um more to ensure contact
            A3200MotionMoveInc(app.handle,app.task,app.Z,-0.05,0.01);
            tic;
            while (toc < 7)
                % Wait for 7 seconds for move ot be done
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Wait 1 minute and store "loaded" coordinates before proceeding
            str = {'Module in place; waiting 1 minute for glue to settle...'};
            writeOutput(app, str);
            PetalUpdate(app,app.ModuleDropDown.Value, 1);
            pause(3);
            endXYZ(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
            endXYZ(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
            endXYZ(3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            app.store(2) = A3200StatusGetItem(app.handle, app.U, A3200StatusItem.PositionFeedback, 0);
            if (~strcmp(cal,'calibrate')) % If not calibrating, wait rest of minute
                pause(57);
            end
            
            % Turn off gantry vacuum
            Vacuum(app, 'gantry', 0);
            pause(2);
            
            % Lift gantry head up (slowly)
            motion = MoveZ(app, (endXYZ(3) + 2), 0.25);
            if (~motion)
                return;
            end
            
            str = {'Proceeding to survey placement...'};
            writeOutput(app, str);
            % Survey placement
            [offsets, motion, cals] = Survey(app, app.ModuleDropDown.Value);
            if (~motion)
                return;
            end
            
            if (strcmp(cal,'calibrate'))
                % If calibrate was selected, calculate camera offsets:
                camx = cals(1);
                camy = cals(2);
                
                [~,~,data] = xlsread(app.gantryFile);
                str = 'Camera';
                com = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
                row = find(com == 1); % Get Row number
                
                xlrange = sprintf('A%d',row+1);
                xlswrite(app.gantryFile, [camx, camy], 1, xlrange);
                
                str = sprintf('%f, %f',camx,camy);
                str = {'Camera offset determined:',str};
                writeOutput(app, str);
            else
                % Check if any of the errors are greater than 20 um
                adjustFlag = 0;
                if (sqrt(offsets(1)^2 + offsets(2)^2) > 0.02)
                    % If they are, output to user and log file
                    str = {'Module placement error is greater than 20 um!'};
                    writeOutput(app, str);
                    
                    % Then ask user if they would like to adjust the module in place
                    msg = 'Module placement error is greater than 20 um! Would you like to adjust the module?';
                    title = 'Confirm Module Adjustment';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'Yes','No','Cancel'},...
                        'DefaultOption',1,'CancelOption',3);
                    if  (selection == 0)
                        % if user selects 'cancel', return from function
                        return;
                    elseif (selection == 1)
                        % If 'Yes', move to adjust and resurvey the placement (below)
                        str = {'Moving to adjust module...'};
                        writeOutput(app, str);
                        adjustFlag = 1;
                    else
                        %If 'No', move ahead for glue to dry
                        str = {'Module adjustment skipped by user.'};
                        writeOutput(app, str);
                    end
                end
                
                % Adjust (if necessary) and wait for glue to dry
                % * Adjustment and wait written in 'Adjust' function
                motion = Adjust(app, endXYZ(1),endXYZ(2),endXYZ(3), offsets(1),offsets(2), adjustFlag);
                if (~motion)
                    return;
                end
                % Since end location has changed, overwrite with new values (x,y)
                endXYZ(1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                endXYZ(2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                pause(1);
                
                % Turn off gantry vacuum
                Vacuum(app, 'gantry', 0);
                pause(0.5);
                
                % Lift gantry head up (slowly)
                motion = MoveZ(app, (endXYZ(3) + 2), 0.25);
                if (~motion)
                    return;
                end
                
                % Resurvey the module after glue has dried
                [~, motion] = Survey(app, app.ModuleDropDown.Value);
                if (~motion)
                    return;
                end
                pause(1);
                
                % Turn lamp over module on petal to green, indicating module has been successfully placed
                PetalUpdate(app,app.ModuleDropDown.Value, 2);
            end
            
            str = {'Replacing pick-up tool...'};
            writeOutput(app, str);
            % Replace pick-up tool
            % First, move back to where the pick-up tool/module is located on petal
            motion = MoveTo(app, endXYZ(1), endXYZ(2), -1);
            if (~motion)
                return;
            end
            motion = MoveU(app, (app.store(2) + 45));
            if (~motion)
                return;
            end
            motion = MoveZ(app, (endXYZ(3) + 1), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, endXYZ(3), 0.5);
            if (~motion)
                return;
            end
            
            % Turn on vacuum
            Vacuum(app, 'gantry', 1);
            pause(0.5);
            
            % Slowly lift tool off of module
            motion = MoveZ(app, (endXYZ(3) + 0.5), 0.1);
            if (~motion)
                return;
            end
            pause(1);
            motion = MoveZ(app, (endXYZ(3) + 2.5), 0.5);
            if (~motion)
                return;
            end
            motion = MoveZ(app, (endXYZ(3) + 40), -1);
            if (~motion)
                return;
            end
            
            % Move tool back to tool holder
            motion = MoveTo(app, -1, tempY, -1);
            if (~motion)
                return;
            end
            motion = MoveTo(app, tempX, 1, -1);
            if (~motion)
                return;
            end
            motion = MoveU(app, tempU);
            if (~motion)
                return;
            end
            motion = MoveZ(app, (tempZ + 2), -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, tempZ, 0.5);
            if (~motion)
                return;
            end
            
            % Turn off vacuum
            Vacuum(app, 'gantry', 0);
            pause(0.5);
            
            % Move gantry head up to safe height
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            
            app.PlaceButton.Enable = 'on';
            app.ModuleDropDown.Enable = 'on';
            
            str = {'Module has been successfully placed!'};
            writeOutput(app, str);
            % Draw line in output
            fID = fopen(app.outputFileTmp,'at');
            fprintf(fID, '------------------------------------------------\n\n');
            fclose(fID);
            printOutput(app);
            
            app.PlaceButton.Enable = 'on';
            app.GlueButton.Enable = 'on';
            app.CalibrateButton.Enable = 'on';
            app.ModuleDropDown.Enable = 'on';
            app.HomeAllButton.Enable = 'on';
            
            if (strcmp(cal,'calibrate'))
                % If calibration was done, change color of calibrate button ot green
                app.CalibrateButton.BackgroundColor = [0 1 0];
                app.PetalButton.Enable = 'on';
                app.PetalLamp.Color = [0.8 0.8 0.8];
                
                app.PlaceButton.Enable = 'off';
                app.GlueButton.Enable = 'off';
                app.CalibrateButton.Enable = 'off';
                app.ModuleDropDownLabel.Enable = 'off';
                app.ModuleDropDown.Enable = 'off';
            end
            
            % Un-lock interface from user
            app.Lock = 0;
            Moving(app,0);
        end
        %%-----------------------------------------------------------------------%%
        % Function for "force-sensing"
        function touchDown(app, thresh, vel)
            % Ensure all motion has stopped
            A3200MotionAbort(app.handle, app.Z);
            pause(0.5);
            % Set a maximum travel distance
            A3200MotionMoveInc(app.handle,app.task,app.Z,-3,vel);
            pause(3);
            % Record average current feedback values
            current0 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.CurrentFeedbackAverage, 0);
            pause(0.25);
            current1= A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.CurrentFeedbackAverage, 0);
            flag = 1;
            
            while flag > 0
                pause(0.25);
                current2 = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.CurrentFeedbackAverage, 0);
                % Take the difference of two consecutive current measurements (spaced 25 ms apart)
               	compare0 = current0 - current1;
               	compare1 = current1 - current2;
                app.OutputTextArea.Value = (sprintf('%g\n%g',compare0,compare1));
                % Check to see if the differences are both consistent
                %  --> if they are, break from the loop and stop motion
               	if (compare0 > thresh)
                    if (compare1 > thresh)
                        flag = -1;
                    end
               	end
               	
               	current0 = current1;
               	current1 = current2;
            end
            % Stop motion and move 50 um away to reduce pressure
            A3200MotionAbort(app.handle, app.Z);
            A3200MotionMoveInc(app.handle,app.task,app.Z,0.05,0.02);
            pause(5);
        end
        %%-----------------------------------------------------------------------%%
        % Function for turning on specified axis light over petla image in GUI
        function PetalUpdate(app, str, state)
            % Check which side of the petal is being done
            if (strcmp(app.Switch.Value,'Side A'))
                petalTmp = imread('PetalImgA_Edit.png');
                side = 1;
            else
                petalTmp = imread('PetalImgB_Edit.png');
                side = 2;
            end
            
            if (strcmp('R0', str))
                % Check if sensor is being placed or alreayd has been placed
                if (state) % Module has been placed
                    app.modulePlaced(side,1) = state;
                    coverImg = imread('R0.png');
                elseif (~state) % Module is being placed
                    app.modulePlaced(side,1) = state;
                    coverImg = imread('R0_tmp.png');
                else % Glue is drying
                    app.modulePlaced(side,1) = -1;
                    coverImg = imread('R0_glue.png');
                end
                
                % Update "Edit" image of petal with sensor
                for i = 1:size(coverImg,1)
                    for j = 1:size(coverImg,2)
                        if (coverImg(i,j,3) < 245)
                            if (side)
                                petalTmp(i+89,j+9,:) = coverImg(i,j,:);
                            else % (side == 2)
                                petalTmp(i+1,j+1,:) = coverImg(i,j,:);
                            end
                        end
                    end
                end
                
                imshow(petalTmp,'Parent',app.PetalPic);
            elseif (strcmp('R1', str))
                
            elseif (strcmp('R2', str))
                
            elseif (strcmp('R3a', str))
                
            elseif (strcmp('R3b', str))
                
            elseif (strcmp('R4a', str))
                
            elseif (strcmp('R4b', str))
                
            elseif (strcmp('R5a', str))
                
            else %(strcmp('R5b', str))
                
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for loading in correct module data for placing
        function Search(app, value)
            [~,~,data] = xlsread(app.gantryFile);
            if (strcmp(value, 'R0') == 0 && strcmp(value, 'R1') == 0 && strcmp(value, 'R2') == 0)
                if (strcmp(value, 'R3a') || strcmp(value, 'R3b') == 1)
                    value = 'R3';
                elseif (strcmp(value, 'R4a') || strcmp(value, 'R4b') == 1)
                    value = 'R4';
                else %(strcmp(value, 'R5a') || strcmp(value, 'R5b') == 1)
                    value = 'R5';
                end
            end
            
            com = strcmpi(value, data(:,1));
            row = find(com == 1);
            
            app.sensorXYZ1 = cell2mat([data(row(1)+1,1) data(row(1)+1,2) data(row(1)+1,3)]);
            app.sensorXYZ2 = cell2mat([data(row(1)+2,1) data(row(1)+2,2) data(row(1)+2,3)]);
            
            app.toolXYZ = cell2mat([data(row(2)+1,1) data(row(2)+1,2) data(row(2)+1,3)]);
            
            app.endLength = cell2mat(data(row(1)+4,1));
            app.endAngle = cell2mat(data(row(1)+4,2));
            app.offLength = cell2mat(data(row(1)+4,3));
            app.offAngle = cell2mat(data(row(1)+4,4));
            app.holdAngle = cell2mat(data(row(1)+4,5));
            app.petalEnd = cell2mat(data(row(1)+4,6));
            
            app.strMod1 = sprintf('A%d:C%d', row(1)+1, row(1)+1);
            app.strMod2 = sprintf('A%d:C%d', row(1)+2, row(1)+2);
            app.strOth = sprintf('A%d:F%d', row(1)+4, row(1)+4);
        end
        %%-----------------------------------------------------------------------%%
        % Function for outputting/adding log data to text file
        function writeOutput(app,text)
            if (~isempty(app.outputFileTmp))
                % Open text file determined at beginning of code
                fID = fopen(app.outputFileTmp,'at');
                % Get and write time of day
                dayTime = timeofday(datetime('now'));
                fprintf(fID, '%s',dayTime);
                % Print requested text lines
                for i = 1:size(text,2)
                    if (i == 1)
                        fprintf(fID, '  %s\n',string(text(i)));
                    else
                        fprintf(fID, '                %s\n',string(text(i)));
                    end
                end
                fprintf(fID, '\n');
                % Close file
                fclose(fID);
                
                % Call function to print out text to output
                printOutput(app);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for outputting log data to GUI 'Output Area'
        function printOutput(app)
            outputString = fileread(app.outputFileTmp);
            app.OutputTextArea.Value = sprintf('%s',outputString);
        end
        %%-----------------------------------------------------------------------%%
        % Function for asking user to click point on camera view to move to
        function find = circleFeedback(app)
            listInc = app.IncrementalTab.Children;
            % Turn on all buttons in 'Incrmenetal' movement tab
            for i = 1:size(listInc,1)
                listInc(i).Enable = 'on';
            end
            % Give user info about controls
            text = 'Find circle using mouse clicks on camera view:';
            menu = feedbackMenu(app, text);
            
            % Continue getting user input until the 'Return' key is pressed
            while (1)
                % Call built-in function for finding point on figure
                try % try-catch statement in case app is closed and ginput is still active
                    [x,y,button] = ginput(1);
                    pause(0.1);
                    
                    if (button == 3)
                        % If 'Right-click', manual find:
                        find = 1;
                        break;
                    elseif (button == 32)
                        % If 'Space', try fit algorithm again:
                        find = fitCircle(app);
                        break;
                    elseif (button == 61 || button == 45)
                        % If '+/=' key is pressed, zoom in/move down (by above amount):
                        
                        % Calculate time take for zooming in/out (use with key presses)
                        zoomSize = app.IncStepSize_Z.Value;
                        zoomTime = 2*sqrt(app.accelTime*zoomSize/0.1);
                        
                        % Check for step size
                        if (~zoomSize)
                            msg = 'Input non-zero value for "Incremental" Z step size!';
                            title = 'Find Circle';
                            uialert(app.ModulePlacementUIFigure,msg,title,...
                                'Icon','Warning');
                        end
                        
                        if (button == 61)
                            A3200MotionMoveInc(app.handle, app.task, app.Z, -zoomSize, 0.1);
                        else
                            A3200MotionMoveInc(app.handle, app.task, app.Z, zoomSize, 0.1);
                        end
                        
                        tic;
                        while (toc < zoomTime + 1)
                            Collect(app,0);
                            pause(0.0001);
                        end
                        Collect(app,1);
                        pause(0.001);
                    elseif (button == 1)
                        % Display current camera image and obtain diimensions
                        img = getsnapshot(app.cam);
                        imshow(img, 'InitialMagnification',app.scale);
                        offsetX = size(img,2)/2;
                        offsetY = size(img,1)/2;
                        
                        % Relate pixel locations to the centre of image
                        offsetX = (x - offsetX)/3;
                        offsetY = (offsetY - y)/3;
                        
                        % Convert pixel locations to distance (in mm)
                        dx = abs(offsetX)/1000;
                        dy = abs(offsetY)/1000;
                        hyp = sqrt(dx^2 + dy^2);
                        velX = app.velocity*dx/hyp;
                        velY = app.velocity*dy/hyp;
                        
                        % Calculate how long the movement will take ('totalTime')
                        if (hyp > app.velocity*app.accelTime)
                            totalTime = app.accelTime + hyp/app.velocity;
                        else
                            totalTime = 2*sqrt(app.accelTime*hyp/app.velocity);
                        end
                        
                        % Move gantry calculated distance
                        A3200MotionMoveInc(app.handle, app.task, app.X, offsetX/1000, velX);
                        A3200MotionMoveInc(app.handle, app.task, app.Y, offsetY/1000, velY);
                        
                        % Start timer to wait for movement to finish
                        tic;
                        while (toc < totalTime + 1.5)
                            Collect(app,0);
                            pause(0.0001);
                        end
                        Collect(app,1);
                        pause(0.001);
                        
                        % Add crosshairs for user feedback
                        x = size(img,2)/2;
                        y = size(img,1)/2;
                        % ('x' line)
                        xL1 = [(x-50) (x+50)];
                        yL1 = [y y];
                        % ('y' line)
                        xL2 = [x x];
                        yL2 = [(y-50) (y+50)];
                        line(xL1, yL1, 'Color', 'red');
                        line(xL2, yL2, 'Color', 'red');
                        drawnow;
                    else
                        % if any other key/button is pressed,
                        % do nothing (ie. continue loop)
                    end
                catch
                end
            end
            delete(menu);
            % Turn off all buttons in 'Incrmenetal' movement tab
            for i = 1:size(listInc,1)
                listInc(i).Enable = 'off';
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for asking user to click point on camera view to move to
        function find = fitFeedback(app, ang)
            find = ang;
            listInc = app.IncrementalTab.Children;
            % Turn on all buttons in 'Incrmenetal' movement tab
            for i = 1:size(listInc,1)
                listInc(i).Enable = 'on';
            end
            % Give user info about controls
            text = 'Find "F" using mouse clicks on camera view:';
            menu = feedbackMenu(app, text);
            
            % Continue getting user input until the 'Return' key is pressed
            while (1)
                % Call built-in function for finding point on figure
                try % try-catch statement in case app is closed and ginput is still active
                    [x,y,button] = ginput(1);
                    pause(0.1);
                    
                    if (button == 3)
                        % If 'Right-click', manual find:
                        find = 'found';
                        break;
                    elseif (button == 32)
                        % If 'Space', try fit algorithm again:
                        % Ask user for new angular search (if needed)
                        msg = 'Enter new starting angle:';
                        title = 'Start angle';
                        answer = inputdlg(msg,title,[1,50],{num2str(ang)});
                        if  (isempty(answer))
                            % if user selects 'Cancel', continue looping
                        else
                            find = str2double(string(answer));
                            break;
                        end
                    elseif (button == 61 || button == 45)
                        % If '+/=' key is pressed, zoom in/move down (by above amount):
                        
                        % Calculate time take for zooming in/out (use with key presses)
                        zoomSize = app.IncStepSize_Z.Value;
                        zoomTime = 2*sqrt(app.accelTime*zoomSize/0.1);
                        
                        % Check for step size
                        if (~zoomSize)
                            msg = 'Input non-zero value for "Incremental" Z step size!';
                            title = 'Find Circle';
                            uialert(app.ModulePlacementUIFigure,msg,title,...
                                'Icon','Warning');
                        end
                        
                        if (button == 61)
                            A3200MotionMoveInc(app.handle, app.task, app.Z, -zoomSize, 0.1);
                        else
                            A3200MotionMoveInc(app.handle, app.task, app.Z, zoomSize, 0.1);
                        end
                        
                        tic;
                        while (toc < zoomTime + 1)
                            Collect(app,0);
                            pause(0.0001);
                        end
                        Collect(app,1);
                        pause(0.001);
                    elseif (button == 1)
                        % Display current camera image and obtain diimensions
                        img = getsnapshot(app.cam);
                        imshow(img, 'InitialMagnification',app.scale);
                        offsetX = size(img,2)/2;
                        offsetY = size(img,1)/2;
                        
                        % Relate pixel locations to the centre of image
                        offsetX = (x - offsetX)/3;
                        offsetY = (offsetY - y)/3;
                        
                        % Convert pixel locations to distance (in mm)
                        dx = abs(offsetX)/1000;
                        dy = abs(offsetY)/1000;
                        hyp = sqrt(dx^2 + dy^2);
                        velX = app.velocity*dx/hyp;
                        velY = app.velocity*dy/hyp;
                        
                        % Calculate how long the movement will take ('totalTime')
                        if (hyp > app.velocity*app.accelTime)
                            totalTime = app.accelTime + hyp/app.velocity;
                        else
                            totalTime = 2*sqrt(app.accelTime*hyp/app.velocity);
                        end
                        
                        % Move gantry calculated distance
                        A3200MotionMoveInc(app.handle, app.task, app.X, offsetX/1000, velX);
                        A3200MotionMoveInc(app.handle, app.task, app.Y, offsetY/1000, velY);
                        
                        % Start timer to wait for movement to finish
                        tic;
                        while (toc < totalTime + 1.5)
                            Collect(app,0);
                            pause(0.0001);
                        end
                        Collect(app,1);
                        pause(0.001);
                        
                        % Add crosshairs for user feedback
                        x = size(img,2)/2;
                        y = size(img,1)/2;
                        % ('x' line)
                        xL1 = [(x-50) (x+50)];
                        yL1 = [y y];
                        % ('y' line)
                        xL2 = [x x];
                        yL2 = [(y-50) (y+50)];
                        line(xL1, yL1, 'Color', 'red');
                        line(xL2, yL2, 'Color', 'red');
                        drawnow;
                    else
                        % if any other key/button is pressed,
                        % do nothing (ie. continue loop)
                    end
                catch
                end
            end
            delete(menu);
            % Turn off all buttons in 'Incrmenetal' movement tab
            for i = 1:size(listInc,1)
                listInc(i).Enable = 'off';
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for ending/closing GUI
        function endProgram(app)
            % Stop any motion before stopping/closing the app
            A3200MotionAbort(app.handle, app.allAxes);
            
            % Print out that program has been cancelled
            str = 'Program cancelled by user.';
            writeOutput(app, str);
            
            tic;
            while (toc < 3)
                % Wait 3 seconds before closing GUI
            end
            
            delete(app.cam);    % stop the camera
            delete(app.screen); % close camera figure
            delete(app)         % close GUI
        end

        %%-----------------------------------------------------------------------%%
        % Function for fitting module fiducials
        function fit(app, angSt)
            pause(0.5);
            
            srch = 1; % set to 1 for finding angle; 0 for using "ang" variable below
            Mncc = zeros(360,1);    % array used to stor correlation values
            
            % Template image(s)
            if (strcmp(app.fid,'F') == 1)
                fidName = sprintf('%sF_Standard.bmp',app.fiducialDirectory);
                if (isfile(fidName) && isfile(fidName))
                    % Store reference images in variables to be used in fit
                    refImage = rgb2gray(imread(fidName));
                    outLine = rgb2gray(imread(fidName));
                else
                    % Ask user to locate files?
                    [file,path] = uigetfile('*.bmp',...
                        'Please find the two reference images for fit', ...
                        'MultiSelect', 'on');
                    if isequal(file,0)
                        % User selected cancel...
                        return;
                    else
                        if (strcmp(char(file(1)),'F_Standard.bmp'))
                            refImage = fullfile(path, string(file(1)));
                            outLine = fullfile(path, string(file(2)));
                            refImage = rgb2gray(imread(char(refImage)));
                            outLine = rgb2gray(imread(char(outLine)));
                        else
                            refImage = fullfile(path, string(file(2)));
                            outLine = fullfile(path, string(file(1)));
                            refImage = rgb2gray(imread(char(refImage)));
                            outLine = rgb2gray(imread(char(outLine)));
                        end
                    end
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            while(1)
                % Get screenshot from camera view
                img = flip(getsnapshot(app.cam),1);
                rgbImage = img;
                imWidth = size(img,2);
                imHeight = size(img,1);
                
                % Binarize image and convert to double
                for i = 1:1:imWidth
                    for j = 1:1:imHeight
                        if rgbImage(j,i) < 65
                            rgbImage(j,i) = 255;
                        else
                            rgbImage(j,i) = 0;
                        end
                    end
                end
                rgbImage = im2double(rgbImage);
                
                % Test Image
                %imshow(rgbImage,'InitialMagnification',app.scale);
                pause(0.001);
                %------------
                
                if (srch)
                    % Cycle through 5 degrees to find best angle for fit
                    for i = angSt:(angSt + 5)
                        % Calculate NCC between Template and Image
                        NCC = templateMatching(app, im2double(rotateGPU(app, refImage, i)), rgbImage);
                        [x,y] = find(NCC == max(NCC(:)));
                        Mncc(i-angSt+1) = NCC(x(1),y(1));
                    end
                    % Find which angle had the best fit
                    [M,I] = max(Mncc(:));
                    ang = I+angSt-1;
                else
                    % Calculate NCC between Template and Image
                    NCC = templateMatching(app, im2double(rotateGPU(app, refImage, angSt)), rgbImage);
                    [x,y] = find(NCC == max(NCC(:)));
                    Mncc(i-angSt+1) = NCC(x(1),y(1));
                    M = max(Mncc(:));
                    ang = angSt;
                end
                
                if M < 0.8  % Check if the fit is over a threshhold (0.8) or not
                    % if it isn't above threshhold, ask user to move closer and change search angle
                    angSt = fitFeedback(app,ang);
                    if (~isnumeric(angSt))
                        % if feedback does not return a number, continue (manual override)
                        break;
                    end
                else
                    % if it is above threshhold, continue through algorithm
                    break;
                end
            end
            
            if (isnumeric(angSt))
                refImage = im2double(rotateGPU(app, refImage, ang));
                % Calculate SSD and NCC between Template and Image
                NCC = templateMatching(app, refImage, rgbImage);
                % Find maximum correspondence in SDD image
                [y,x] = find(NCC == max(NCC(:)));
                
                % Enlarge figure to full screen.
                outLine = imrotate(outLine, ang, 'loose', 'bilinear');
                corrOffset = [round(y(1)-size(refImage,1)/2+1) round(x(1)-size(refImage,2)/2+1)];
                
                cmap = gray(256);
                % overlay fiducial pattern in place/angle
                for i = 1:1:size(outLine,1)
                    for j = 1:1:size(outLine,2)
                        if outLine(i,j) > 250
                            if (i+corrOffset(1) < 0 || j+corrOffset(2) < 0)
                                text = 'Could not find fiducial\nOut of bounds\n';
                                app.OutputTextArea.Value = sprintf(text);
                                return;
                            end
                            img(i+corrOffset(1),j+corrOffset(2)) = 255;
                        end
                    end
                end
                
                % Change outline colour to green
                cmap(255,:) = [0 1 0];
                imshow(img,'InitialMagnification',app.scale);
                colormap(cmap);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                x2 = x(1);
                y2 = y(1);
                
                x = imWidth/2;
                y = imHeight/2;
                
                % Add in crosshairs (centre of camera)
                % ('x' line)
                xL = [(x-50) (x+50)];
                yL = [y y];
                line(xL, yL, 'Color', 'red');
                % ('y' line)
                xL = [x x];
                yL = [(y-50) (y+50)];
                line(xL, yL, 'Color', 'red');
                
                % Add camera-fiducial 'offset' line
                xL = [x x2];
                yL = [y y2];
                line(xL, yL, 'Color', 'green');
                % Convert offset to um
                offsetX = (xL(2)-xL(1))/3;
                offsetY = -(yL(2)-yL(1))/3;
                
                text = 'x-offset is: %0.1f um\ny-offset is: %0.1f um\nmax Corr was: %0.2f %%\nat an angle of %0.1f%c\n';
                app.OutputTextArea.Value = sprintf(text, offsetX, offsetY, 100*M, ang, char(176));
                
                A3200MotionMoveInc(app.handle, app.task, app.X, offsetX/1000, 1);
                A3200MotionMoveInc(app.handle, app.task, app.Y, offsetY/1000, 1);
                
                tic;
                while (toc < 2)
                    % pause for 2 seconds for move to finish
                end
                
                % Ask user if fit is good
                msg = 'Was fiducial found correctly?';
                title = 'Fiducial found';
                selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                    'Options',{'Yes','No'},...
                    'DefaultOption',1,'CancelOption',2);
                if (strcmp(selection,'No'))
                    % If 'No', change 'output' to zero and proceed to manual fit
                    %output = 0;
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for fitting module fiducials
        function NCC = templateMatching(~, ref, img)
            % Calculate correlation output size  = input size + padding template
            refSize = size(ref);
            imgSize = size(img);
            outSize = imgSize + refSize - 1;
            
            % calculate correlation in frequency domain
            FT = fft2(rot90(ref,2), outSize(1), outSize(2));
            imData.FI = fft2(img, outSize(1), outSize(2));
            imCorr = real(ifft2(imData.FI.*FT));
            
            % Calculate Local Quadratic sum of Image and Template
            % Add padding to the image
            newImg = padarray(img, refSize);
            
            % Calculate for each pixel the sum of the region around it,
            % with the region the size of the template.
            cSum = cumsum(newImg, 1);
            mod = cSum(1 + refSize(1):end - 1,:) - cSum(1:end - refSize(1) - 1,:);
            cSum = cumsum(mod, 2);
            imData.LocalQSumI = cSum(:, 1 + refSize(2):end - 1) - cSum(:, 1:end - refSize(2) - 1);
            
            % Normalized cross correlation STD
            % Add padding to the image
            newImg = padarray(img, refSize);
            
            % Calculate for each pixel the sum of the region around it,
            % with the region the size of the template.
            cSum = cumsum(newImg, 1);
            mod = cSum(1 + refSize(1):end - 1,:) - cSum(1:end - refSize(1) - 1,:);
            cSum = cumsum(mod, 2);
            imData.LocalSumI = cSum(:, 1 + refSize(2):end - 1) - cSum(:, 1:end - refSize(2) - 1);
            % Standard deviation
            imData.stdI = sqrt(max(imData.LocalQSumI - (imData.LocalSumI.^2)/numel(ref), 0));
            refStd = sqrt(numel(ref) - 1)*std(ref(:));
            % Mean compensation
            imRefAve = imData.LocalSumI*sum(ref(:))/numel(ref);
            NCC = 0.5 + (imCorr - imRefAve)./(2*refStd*max(imData.stdI, refStd/1e5));
            % Remove padding
            imStart = ceil((size(NCC) - size(img))/2) + 1;
            imEnd = imStart + size(img) - 1;
            NCC = NCC(imStart(1):imEnd(1), imStart(2):imEnd(2));
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for 'templateMatching' algorithm/function
        function Output = rotateGPU(~, image, ang)
            % Find the midpoint of image
            if(ang > 155)
                midx = ceil((size(image,1))/2);
                midy = ceil((size(image,2))/2);
            else
                midx = ceil((size(image,2))/2);
                midy = ceil((size(image,1))/2);
            end
            
            [y,x] = meshgrid(1:size(image,2), 1:size(image,1));
            [t,r] = cart2pol(x - midx, y - midy);
            t = deg2rad(rad2deg(t) + ang);
            
            % Convert to Cartesian Co-ordinates
            [x,y] = pol2cart(t,r);
            
            % Add the mid points to the new co-ordinates
            tempx = round(x + midx);
            tempy = round(y + midy);
            
            if (min(tempx(:)) < 0)
                newx = max(tempx(:)) + abs(min(tempx(:))) + 1;
                tempx = tempx + abs(min(tempx(:))) + 1;
            else
                newx = max(tempx(:));
            end
            
            if (min(tempy(:)) < 0)
                newy = max(tempy(:)) + abs(min(tempy(:))) + 1;
                tempy = tempy + abs(min(tempy(:))) + 1;
            else
                newy = max(tempy(:));
            end
            
            tempy(tempy == 0) = 1;
            tempx(tempx == 0) = 1;
            
            img = uint8(zeros([newx newy]));
            
            for i = 1:size(image,1)
                for j = 1:size(image,2)
                    img(tempx(i,j), tempy(i,j)) = image(i,j);
                end
            end
            Output = img;
            
            % Fill the holes/gaps
            for i = 2:(size(img,1) - 1)
                for j = 2:(size(img,2) - 1)
                    temp = img(i-1:i+1,j-1:j+1);
                    if(temp(5) == 0 && sum(temp(:)) ~= 0)
                        pt = find(temp ~= 0);
                        [~,pos] = sort(abs(pt-5));
                        Output(i,j) = temp(pt(pos(1)));
                    end
                end
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for surveying placement results
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function [aveXYZ, motion, cals] = Survey(app, value)
            % Make sure camera is active/on
            if (isempty(app.cam))
                camOn(app,-2);
            end
            
            % Turn on axis light
            Light(app, 'Axis', 1);
            
            % Load in module survey data (for surveying correct module)
            [~,~,data] = xlsread(app.surveyFile);
            com = strcmpi(value, data(:,1));
            row = find(com == 1);
            
            fleng = cell2mat([data(row(1)+1,1) data(row(1)+1,2) data(row(1)+1,3) data(row(1)+1,4)]);
            fang = cell2mat([data(row(1)+2,1) data(row(1)+2,2) data(row(1)+2,3) data(row(1)+2,4)]);
            
            % Move to a safe height before proceeding
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
            
            % Adjust angles of locations of 'F' fiducials based on petal orientation
            newAng = zeros(4,1);
            for i = 1:4
                newAng(i) = fang(i) + app.petalAngle;
            end
            
            % Compute x/y values of 'F' fiducials based on above angles
            newX = zeros(4,1);
            newY = newX;
            for i = 1:4
                if (newAng(i) > 360)
                    newAng(i) = newAng(i) - 360;
                end
                newX(i) = cos(deg2rad(newAng(i)))*fleng(i);
                newY(i) = sin(deg2rad(newAng(i)))*fleng(i);
            end
            
            % Go through 'F' fiducials
            expectedXYZ = zeros(12,1);
            measuredXYZ = expectedXYZ;
            modXYZ = expectedXYZ;
            
            for i = 1:4
                motion = MoveTo(app, newX(i) + app.petalXYZ1(1) - app.camera(1), newY(i) + app.petalXYZ1(2) - app.camera(2), -1);
                if (~motion)
                    return;
                end
                motion = MoveZ(app,-93.0850,-1);
                if (~motion)
                    return;
                end
                
                pause(1);
                autoFocus(app);
                pause(1);
                
                % Store coordinates of location after moving to expected position of fiducial
                expectedXYZ(3*(i - 1) + 1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                expectedXYZ(3*(i - 1) + 2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                expectedXYZ(3*(i - 1) + 3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                
                % Fit 'F' (change starting angle for search depending on which 'F' is being fitted)
                app.fid = 'F';
                if (i < 3)
                    app.startAngle = -89;
                else
                    app.startAngle = -105;
                end
                fit(app, app.startAngle);
                
                % Store coordinates of location of actual fiducial position
                pause(2);
                measuredXYZ(3*(i - 1) + 1) = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                measuredXYZ(3*(i - 1) + 2) = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                measuredXYZ(3*(i - 1) + 3) = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
            end
            
            % Turn off axis light
            Light(app, 'Axis', 0);
            
            % Calculate the x/y offsets
            for i = 1:12
                modXYZ(i) = measuredXYZ(i) - expectedXYZ(i);
            end
            
            % Calculate the average offset
            aveXYZ = zeros(3,1);
            for i = 1:3
                aveXYZ(i) = (modXYZ(i) + modXYZ(i + 3) + modXYZ(i + 6) + modXYZ(i + 9))/4;
            end
            
            % Calculate angular offset
            offsetAngle = zeros(1,5);
            offsetAngle(2) = atan2(measuredXYZ(5) - measuredXYZ(2),measuredXYZ(4) - measuredXYZ(1)) - atan2(expectedXYZ(5) - expectedXYZ(2),expectedXYZ(4) - expectedXYZ(1));
            offsetAngle(3) = atan2(measuredXYZ(8) - measuredXYZ(5),measuredXYZ(7) - measuredXYZ(4)) - atan2(expectedXYZ(8) - expectedXYZ(5),expectedXYZ(7) - expectedXYZ(4));
            offsetAngle(4) = atan2(measuredXYZ(11) - measuredXYZ(8),measuredXYZ(10) - measuredXYZ(7)) - atan2(expectedXYZ(11) - expectedXYZ(8),expectedXYZ(10) - expectedXYZ(7));
            offsetAngle(5) = atan2(measuredXYZ(2) - measuredXYZ(11),measuredXYZ(1) - measuredXYZ(10)) - atan2(expectedXYZ(2) - expectedXYZ(11),expectedXYZ(1) - expectedXYZ(10));
            offsetAngle(1) = rad2deg((offsetAngle(2) + offsetAngle(3) + offsetAngle(4) + offsetAngle(5))/4);
            
            % Calculate camera offset based on survey results and overwrite values in excel file
            hypo = sqrt(aveXYZ(1)^2 + aveXYZ(2)^2);
            theta = atan2(aveXYZ(2), aveXYZ(1));
            
            dTheta = deg2rad(app.store(1) + app.store(2));
            dHypo = 2*cos(dTheta);
            
            hypo = hypo/dHypo;
            theta = theta - dTheta;
            
            camx = app.camera(1) - hypo*cos(theta);
            camy = app.camera(2) - hypo*sin(theta);
            
            cals = [camx, camy];
            
            % Store values of offsets in file for plotting
            len = size(xlsread(app.surveyResultsFile,'A:A'),1);
            for i = 0:3
                len2 = sprintf('A%d',len+i+1);
                xlswrite(app.surveyResultsFile, {modXYZ(3*i + 1), modXYZ(3*i + 2), modXYZ(3*i + 3), offsetAngle(1)}, 1, len2);
            end
            
            % Move to safe height
            motion = MoveZ(app, -50, -1);
            if (~motion)
                return;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for adjusting placed module and waiting for glue ot dry
        % *** When calling function, be sure to check 'motion' in calling
        %     function/callback if motion was complete or stopped
        function motion = Adjust(app, startX,startY,startZ, offsetX,offsetY, adj)
            % Move back to bridge (slowly touch down)
            motion = MoveZ(app, 50, -1);
            if (~motion)
                return;
            end
            motion = MoveTo(app, startX, startY, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, startZ + 1, -1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, startZ + 0.2, 1);
            if (~motion)
                return;
            end
            motion = MoveZ(app, startZ, 0.05);
            if (~motion)
                return;
            end
            
            % Turn on gantry vacuum
            Vacuum(app, 'gantry', 1);
            pause(0.5);
            
            % Adjust module (if selected)
            if (adj == 1)
                % Calculate hypoteneuse of move
                hyp = sqrt(offsetX^2 + offsetY^2);
                velX = abs(0.005*offsetX/hyp);
                velY = abs(0.005*offsetY/hyp);
                
                str = 'Adjusting module...';
                writeOutput(app, str);
                
                % Lift module 1/3 thickness of glue (150/3 = 50 um) at 5 um/s
                A3200MotionMoveInc(app.handle, app.task, app.Z, 0.05, 0.005);
                
                % Wait for Z-height to be established before moving in x/y
                tic;
                while (toc < 12)
                    Collect(app,0);
                    pause(0.0001);
                end
                Collect(app,1);
                pause(0.001);
                
                % Move module by average offset at 5 um/s
                A3200MotionMoveInc(app.handle, app.task, app.X, offsetX, velX);
                A3200MotionMoveInc(app.handle, app.task, app.Y, offsetY, velY);
                
                % Wait for move to finish (plus 2 extra seconds) before moving module back down
                tic;
                while (toc < hyp/0.005 + 2)
                    Collect(app,0);
                    pause(0.0001);
                end
                Collect(app,1);
                pause(0.001);
                motion = MoveZ(app, startZ, 0.05);
                if (~motion)
                    return;
                end
            end
            
            % Wait for glue to dry before proceeding
            mins = 10; % minutes to wait for
            str = sprintf('Waiting %d minutes for glue to dry.', mins);
            writeOutput(app, str);
            tic;
            while (toc < mins*60)
                Collect(app);
                pause(0.0001);
            end
            Collect(app,1);
            pause(0.001);
        end
        %%-----------------------------------------------------------------------%%
        % Function for finding camera focus
        function autoFocus(app)
            
        end
        %%-----------------------------------------------------------------------%%
        % Function for finding camera focus
        function MoveBy(app, axes, step, vel)
            app.StopMotionButton.Enable = 'on';
            x = 1;
            y = 1;
            z = 1;
            u = 0;
            
            % Set velocity to default velocity if set to '-1'
            if (vel == -1)
                vel = app.velocity;
            end
            
            % Calculate how long the movement will take ('totalTime')
            if (abs(step) > vel*app.accelTime)
                totalTime = app.accelTime + abs(step)/vel;
            else
                totalTime = 2*sqrt(app.accelTime*abs(step)/vel);
            end
            
            % Move in direction chosen in function (axis)
            % (check if movement is possible first!)
            if (strcmp(axes,'x'))
                curX = A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0);
                if (curX + step < 0 || curX + step > 1000)
                    x = 0;
                else
                    A3200MotionMoveInc(app.handle, app.task, app.X, step, vel);
                end
            elseif (strcmp(axes,'y'))
                curY = A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0);
                if (curY + step > 0 || curY + step < -1000)
                    y = 0;
                else
                    A3200MotionMoveInc(app.handle, app.task, app.Y, step, vel);
                end
            elseif (strcmp(axes,'z'))
                curZ = A3200StatusGetItem(app.handle, app.Z, A3200StatusItem.PositionFeedback, 0);
                if (curZ + step > 0 || curZ + step < -120)
                    z = 0;
                else
                    A3200MotionMoveInc(app.handle, app.task, app.Z, step, vel);
                end
            else %(strcmp(axes,'u'))
                vel = app.velocity/4;
                A3200MotionMoveInc(app.handle, app.task, app.U, step, vel);
                u = 1;
            end
            
            % Check if step size was too big:
            % send message and return from function
            if (~x || ~y || ~z)
                msg = sprintf('Incremental step size too large!');
                title = 'Step size too large!';
                uialert(app.ModulePlacementUIFigure,msg,title,...
                    'Icon','Warning');
                return;
            end
            
            % Gray out buttons and enable the emergency stop button while moving
            Moving(app,1);
            
            tic; % Start timer
            % Allow data to be collected and movement to be stopped
            while (toc < totalTime + app.delay) % Collect data for 'app.delay' seconds more than the time of the movement
                if (app.motionStop == 1)
                    Abort(app, [x y z u]);
                    return;
                end
                Collect(app,0);
                pause(0.0001);
            end
            Collect(app,1);
            pause(0.001);
            Moving(app,0);
        end
        %%-----------------------------------------------------------------------%%
        % Function for enabling/disabling buttons based on
        % if the gantry is moving or not
        function Moving(app,active)
            % Get all of the buttons/labels in the foloowing tabs for controlling
            listAbs = app.AbsoluteTab.Children;
            listInc = app.IncrementalTab.Children;
            listAxis = app.AxisPanel.Children;
            
            if (active)
                % Turn on Stop button
                app.StopMotionButton.Enable = 'on';
                % Turn off all buttons in 'Absolute' movement tab
                for i = 1:size(listAbs,1)
                    listAbs(i).Enable = 'off';
                end
                % Turn off all buttons in 'Incrmenetal' movement tab
                for i = 1:size(listInc,1)
                    listInc(i).Enable = 'off';
                end
                % Turn off button in 'Graphical' movement tab
                app.gantryViewButton.Enable = 'off';
                % Turn off Velocity edit field
                app.VelocitymmsEditField.Enable = 'off';
                % Turn off all buttons in 'Axis' panel
                for i = 1:size(listAxis,1)-4
                    listAxis(i).Enable = 'off';
                end
                % Turn off 'extra' buttons
                app.HomeAllButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
            else
                % Turn off Stop button
                app.StopMotionButton.Enable = 'off';
                % Turn on all buttons in 'Absolute' movement tab
                for i = 1:size(listAbs,1)
                    listAbs(i).Enable = 'on';
                end
                % Turn on all buttons in 'Incrmenetal' movement tab
                for i = 1:size(listInc,1)
                    listInc(i).Enable = 'on';
                end
                % Turn on button in 'Graphical' movement tab
                app.gantryViewButton.Enable = 'on';
                % Turn off Velocity edit field
                app.VelocitymmsEditField.Enable = 'on';
                % Turn on all buttons in 'Axis' panel
                for i = 1:size(listAxis,1)-4
                    listAxis(i).Enable = 'on';
                end
                % Turn on 'extra' buttons
                app.HomeAllButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
            end
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for graphically choosing where to move
        function gantryView(app)
            % Create interactive figure
            screenSize = get(0,'ScreenSize');
            app.tabView = figure('name', 'Gantry Top-View', 'NumberTitle', 'off', 'Position', [(screenSize(3) - 1000) (screenSize(4) - 1000) 1000 1000]);
            
            % Display cropped (1m x 1m) top-view of current gantry position
            topView = imread(sprintf('%sGantryTable_Edit.png',app.imageDirectory));
            topView = imcrop(topView,[340 480 999 999]);
            imshow(topView,'InitialMagnification', 83);
            
            % Call built-in function for finding point on figure
            try % try-catch statement in case app is closed and top-view is still open
                [x,y,button] = ginput(1);
                pause(0.1);
                
                % Delete/close the figure
                delete(app.tabView);
                pause(0.1);
                
                if (button == 1)
                    % Move centre og gantry axis to point picked on image
                    MoveTo(app, x,-y, -1);
                elseif (button == 3)
                    % Move camera to point picked on image
                    MoveTo(app, x - app.camera(1),-y - app.camera(2), -1);
                else
                    % If any other button is pressed,
                    % exit from function without moving
                end
            catch
            end
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for modifying gantry top-view image after moving (test2)
        function imageOverwrite(app)
            % Get current gantry location
            curX = round(A3200StatusGetItem(app.handle, app.X, A3200StatusItem.PositionFeedback, 0));
            curY = -round(A3200StatusGetItem(app.handle, app.Y, A3200StatusItem.PositionFeedback, 0));
            
            % Load in individual gantry-reference images
            img = imread(sprintf('%sGantryTable.png',app.imageDirectory));
            imgY = imread(sprintf('%sY_Stage.png',app.imageDirectory));
            imgZ = imread(sprintf('%sZ_Stage.png',app.imageDirectory));
            offset = 0;
            
            % Check if Z-stage is outside of image
            if (curY + size(imgZ,1) + 142 > 1520)
                offset = curY + size(imgZ,1) + 142 - 1520;
            end
            % Y-Beam
            for j = 1:size(imgY,2) % collumn
                for i = 1:size(imgY,1) % row
                    if (imgY(i,j,1) < 245)
                        img(i + curY + 63, j + 21,:) = imgY(i,j,:);
                    end
                end
            end
            % Z-Stage
            for j = 1:size(imgZ,2) % collumn
                for i = 1:(size(imgZ,1) - offset) % row
                    if (imgZ(i,j,1) < 245)
                        img(i + curY + 142, j + curX + 206,:) = imgZ(i,j,:);
                    end
                end
            end
            
            % Ovewrite image file
            fileName = sprintf('%sGantryTable_Edit.png',app.imageDirectory);
            imwrite(img,fileName);
            pause(0.1);
            app.gantryViewButton.Icon = fileName;
            pause(0.1);
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for enabling menus/buttons after logging in
        function controlEnable(app, str)
            app.HomeAllButton.Enable = str;
            app.PetalIDEditField.Enable = str;
            app.PetalIDLabel.Enable = str;
            app.SaveButton.Enable = str;
            
            app.X_Enable.Enable = str;
            app.Y_Enable.Enable = str;
            app.Z_Enable.Enable = str;
            app.U_Enable.Enable = str;
            
            % Only allow control if a DAQ device is connected
            if (app.daqFlag)
                app.ControlCheckBox.Enable = str;
            end
            
            if (strcmp(str,'on'))
                Moving(app,0);
            else
                Moving(app,1);
                app.StopMotionButton.Enable = str;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for updating feedback during movements
        function motion = MoveTime(app, axis, totalTime)
            tic;
            % Allow data to be collected and movement to be stopped
            while (toc < totalTime + app.delay) % Collect data for 'app.delay' seconds more than the time of the movement
                if (app.motionStop == 1)
                    motion = 0;
                    Abort(app, axis);
                    Collect(app,1);
                    return;
                end
                Collect(app,0);
                pause(0.0001);
            end
            Collect(app,1);
            pause(0.001);
            if (~app.Lock)
                Moving(app,0);
            end
            motion = 1;
        end
        %%-----------------------------------------------------------------------%%
        % Function for controlling vacuum
        function Vacuum(app, source, state)
            % Only run function if a DAQ device is connected
            if (app.daqFlag)
                % Check if the gantry or chuck vacuum is being turned on/off
                if (strcmp(source,'gantry'))
                    tmp = app.VAC1;
                    % Change colour of "manual button"
                    if (state)
                        app.GantryVACButton.BackgroundColor = [0 1 0];
                    else
                        app.GantryVACButton.BackgroundColor = [0.96 0.96 0.96];
                    end
                else %(strcmp(source,'chuck'))
                    tmp = app.VAC2;
                    % Change colour of "manual button"
                    if (state)
                        app.ChuckVACButton.BackgroundColor = [0 1 0];
                    else
                        app.ChuckVACButton.BackgroundColor = [0.96 0.96 0.96];
                    end
                end
                
                % Turn on/off vacuum
                app.port(tmp) = state;
                outputSingleScan(app.s,app.port);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for controlling lights
        function Light(app, source, state)
            % Only run function if a DAQ device is connected
            if (app.daqFlag)
                % Check which light is being turned on/off
                if (strcmp(source,'LED1'))
                    tmp = app.LED1;
                    % Change colour of "manual button"
                    if (state)
                        app.LED1Button.BackgroundColor = [0 1 0];
                    else
                        app.LED1Button.BackgroundColor = [0.96 0.96 0.96];
                    end
                elseif (strcmp(source,'LED2'))
                    tmp = app.LED2;
                    % Change colour of "manual button"
                    if (state)
                        app.LED2Button.BackgroundColor = [0 1 0];
                    else
                        app.LED2Button.BackgroundColor = [0.96 0.96 0.96];
                    end
                elseif (strcmp(source,'LED3'))
                    tmp = app.LED3;
                    % Change colour of "manual button"
                    if (state)
                        app.LED3Button.BackgroundColor = [0 1 0];
                    else
                        app.LED3Button.BackgroundColor = [0.96 0.96 0.96];
                    end
                else % (strcmp(source,'Axis'))
                    tmp = app.AXIS;
                    % Change colour of "manual button"
                    if (state)
                        app.AXISButton.BackgroundColor = [0 1 0];
                    else
                        app.AXISButton.BackgroundColor = [0.96 0.96 0.96];
                    end
                end
                
                % Turn on/off light
                app.port(tmp) = state;
                outputSingleScan(app.s,app.port);
            end
        end
        %%-----------------------------------------------------------------------%%
        % Function for controlling vacuum
        function out = formatCamera(app)
            % Dimensions of app
            left = app.ModulePlacementUIFigure.Position(1);
            bottom = app.ModulePlacementUIFigure.Position(2);
            height = app.ModulePlacementUIFigure.Position(4);
            
            % Dimensions of Login Menu
            newWidth = 460;
            newHeight = 170;
            newLeft = left;
            newBottom = bottom + height - newHeight - 30;
            
            % Create formatting figure
            menu = uifigure('Name', 'Format Camera', 'Position', [newLeft newBottom newWidth newHeight]);
            menu.CloseRequestFcn = createCallbackFcn(app, @menuCloseRequest, true);
            % Create AdaptorListBox
            AdaptorListBox = uilistbox(menu, 'Items', {}, 'Position', [11 71 140 79], 'Value', {});
            AdaptorListBox.ValueChangedFcn = createCallbackFcn(app, @AdaptorListBoxValueChanged, true);
            uilabel(menu, 'Position', [11 149 48 22], 'Text', 'Adaptor', 'HorizontalAlignment', 'right');
            % Create CameraListBox
            CameraListBox = uilistbox(menu, 'Items', {}, 'Position', [161 71 140 79], 'Value', {});
            CameraListBox.ValueChangedFcn = createCallbackFcn(app, @CameraListBoxValueChanged, true);
            uilabel(menu, 'Position', [161 149 48 22], 'Text', 'Camera', 'HorizontalAlignment', 'right');
            % Create FormatListBox
            FormatListBox = uilistbox(menu, 'Items', {}, 'Position', [311 71 140 79], 'Value', {});
            FormatListBox.ValueChangedFcn = createCallbackFcn(app, @FormatListBoxValueChanged, true);
            uilabel(menu, 'Position', [315 149 48 22], 'Text', 'Format', 'HorizontalAlignment', 'right');
            % Create OKButton
            OKButton = uibutton(menu, 'push', 'Position', [111 21 80 30], 'Text', 'OK', 'Enable', 'off');
            OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            % Create CancelButton
            CancelButton = uibutton(menu, 'push', 'Position', [271 21 80 30], 'Text', 'Cancel');
            CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            % ----------------------------------------------------------------------------------------------- %
            data = imaqhwinfo;
            adSize = size(data.InstalledAdaptors,2);
            tmpArray = cell(1,adSize);
            for i = 1:adSize
                tmpArray(i) = data.InstalledAdaptors(i);
            end
            AdaptorListBox.Items = tmpArray;
            
            uiwait;
            
            % Function for selecting Camera Adaptor
            function AdaptorListBoxValueChanged(~, ~)
                value = AdaptorListBox.Value;
                data = imaqhwinfo(value);
                
                adSize = size(data.DeviceIDs,2);
                tmpArray = cell(1,adSize);
                for i = 1:adSize
                    tmpArray(i) = cellstr(data.DeviceInfo(i).DeviceName);
                end
                CameraListBox.Items = tmpArray;
                CameraListBox.ItemsData = linspace(1,adSize,adSize);
            end
            
            % Function for selecting Camera Name
            function CameraListBoxValueChanged(~, ~)
                value = CameraListBox.Value;
                
                adSize = size(data.DeviceInfo(value).SupportedFormats,2);
                if (adSize > 1)
                    tmpArray = cell(1,adSize);
                    for i = 1:adSize
                        tmpArray(1,i) = cellstr(data.DeviceInfo(value).SupportedFormats(i));
                    end
                    FormatListBox.Items = tmpArray;
                end
            end
            
            % Function for selecting Camera Format
            function FormatListBoxValueChanged(~, ~)
                OKButton.Enable = 'on';
            end
            
            % Function for clicking 'OK' button
            function OKButtonPushed(app, ~)
                app.cameraAdaptor = AdaptorListBox.Value;
                app.cameraID = CameraListBox.Value;
                app.cameraName = char(cell2mat(CameraListBox.Items(app.cameraID)));
                app.cameraFormat = FormatListBox.Value;
                
                [~,~,tmp] = xlsread(app.gantryFile);
                
                str = 'Camera';
                com = strcmpi(str, tmp(:,1));   % Compare user input string with entries in the Excel sheet
                row = find(com == 1); %Get Row number
                
                xlswrite(app.gantryFile, {app.cameraAdaptor}, sprintf('A%d:A%d',row+2,row+2));
                xlswrite(app.gantryFile, {app.cameraName}, sprintf('B%d:B%d',row+2,row+2));
                xlswrite(app.gantryFile, {app.cameraFormat}, sprintf('C%d:C%d',row+2,row+2));
                xlswrite(app.gantryFile, app.cameraID, sprintf('D%d:D%d',row+2,row+2));
                
                delete(menu);
                uiresume;
                out = 1;
            end
            
            % Function for clicking 'Cancel' button
            function CancelButtonPushed(~, ~)
                delete(menu);
                uiresume;
                out = 0;
            end
            
            function menuCloseRequest(~,~)
                delete(menu);
                uiresume;
                out = 0;
            end
        end
        %%-----------------------------------------------------------------------%%
        % Helper function for feedback functions
        function menu = feedbackMenu(app, text)
            % Dimensions of app
            left = app.screen.Position(1);
            bottom = app.screen.Position(2);
            width = app.screen.Position(3);
            height = app.screen.Position(4);
            
            % Dimensions of dialog Menu
            newLeft = left - 3*width/4;
            newBottom = bottom + 3*height/4;
            newWidth = 3*width/4;
            newHeight = height/4;
            
            % Create pop-up window for logging in
            menu = uifigure('name','Controls', 'Position', [newLeft newBottom newWidth newHeight]);
            
            % Inform user to manually select where to move on the camera view
            % until the 'Return' key is pressed
            uilabel(menu, 'Position',[newWidth/8 (3*newHeight/4) 3*newWidth/4 30], 'FontSize',22, 'Text',text);
            uilabel(menu, 'Position',[newWidth/8 (3*newHeight/4 - 30) 3*newWidth/4 30], 'FontSize',22, 'Text',' - Left-click to move to location');
            uilabel(menu, 'Position',[newWidth/8 (3*newHeight/4 - 60) 3*newWidth/4 30], 'FontSize',22, 'Text',' - Right-click to proceed with current location');
            uilabel(menu, 'Position',[newWidth/8 (3*newHeight/4 - 90) 3*newWidth/4 30], 'FontSize',22, 'Text',' - "Space" to try fit again');
            uilabel(menu, 'Position',[newWidth/8 (3*newHeight/4 - 120) 3*newWidth/4 30], 'FontSize',22, 'Text',' - "+"/"-" to zoom in/out');
        end
    end
    %%----------------------------------------------------------------------------------------------------------------------------------------------------%%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize list
            dialog1 = 'Connecting to DAQ..............';
            dialog2 = 'Retrieving coordinate data...';
            dialog3 = 'Connecting to gantry............';
            dialog4 = 'Setting up camera................';
            
            % Check if the Admin Folder (for locking files) is open/closed
            if (exist('AdminFolder', 'dir') == 7)
                locker = app.unlockFile;
            else
                locker = app.lockFile;
            end
            
            % Determine which folder this file is in, and add it, as well as all other related folders, to the path
            app.folder = fileparts(which(mfilename('fullpath')));
            app.folder = erase(app.folder,locker);
            addpath(genpath(app.folder));
            
            % Setup read/write file locations and directories
            app.outputFile = sprintf('%s%s',app.folder,app.outputFile);
            app.outputFileTmp = sprintf('%s%s',app.folder,app.outputFileTmp);
            app.imageDirectory = sprintf('%s%s',app.folder,app.imageDirectory);
            app.fiducialDirectory = sprintf('%s%s',app.folder,app.fiducialDirectory);
            app.surveyResultsFile = sprintf('%s%s',app.outputFile,app.surveyResultsFile);
            app.imageOutDirectory = sprintf('%s%s',app.folder,app.imageOutDirectory);   
            
            app.gantryFile = sprintf('%s%s%s',app.folder,locker,app.gantryFile);
            app.surveyFile = sprintf('%s%s%s',app.folder,locker,app.surveyFile);
            app.glueFile = sprintf('%s%s%s',app.folder,locker,app.glueFile);
            app.userFile = sprintf('%s%s%s',app.folder,locker,app.userFile);

            % Store petal image and show petal
            app.petalImg = imread('PetalImgA.png');
            imshow(app.petalImg,'Parent',app.PetalPic);
            
            % Show button images
            app.gantryViewButton.Icon = 'GantryTable.png';
            
            icon = 'Home.png';
            app.X_Home.Icon = icon;
            app.Y_Home.Icon = icon;
            app.Z_Home.Icon = icon;
            app.U_Home.Icon = icon;
            
            icon = 'Disable.png';
            app.X_Enable.Icon = icon;
            app.Y_Enable.Icon = icon;
            app.Z_Enable.Icon = icon;
            app.U_Enable.Icon = icon;
            %---------------------------------------------------%
            % add appropriate number of control ports for NI controller
            app.OutputTextArea.Value = sprintf('%s',dialog1);
            portText = sprintf('Port0/Line0:%d',app.num-1);
            app.port = zeros(1,app.num);
            app.s = daq.createSession('ni');
            strDAQ = 'Done.';
            
            try % try/catch to test if DAQ is connected/running
                addDigitalChannel(app.s,'cDAQ1Mod1',portText,'OutputOnly');
            catch
                strDAQ = 'Failed.';
            end
            
            if (strcmp(strDAQ,'Done.'))
                outputSingleScan(app.s,app.port);
                app.daqFlag = 1;
            end
            %---------------------------------------------------%
            % Setup filenames and load program variables from file(s)
            app.surveyFile = sprintf('%s%s',app.outputFile,app.surveyFile);
            app.surveyResultsFile = sprintf('%s%s',app.outputFile,app.surveyResultsFile);
            app.glueFile = sprintf('%s%s',app.outputFile,app.glueFile);
            
            app.OutputTextArea.Value = sprintf('%s\t%s\n%s',dialog1,strDAQ,dialog2);
            
            if (isfile(app.gantryFile))
                [~,~,data] = xlsread(app.gantryFile);
                
                app.petalAngle = 0;  % value of measured angle of petal (after measuring fiducial locations)
                
                str = 'Petal Coordinates';
                com = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
                row = find(com == 1); %Get Row number
                app.petalXYZ1 = cell2mat([data(row+1,1) data(row+1,2) data(row+1,3)]);
                app.petalXYZ2 = cell2mat([data(row+2,1) data(row+2,2) data(row+2,3)]);
                
                str = 'Camera';
                com = strcmpi(str, data(:,1));   % Compare user input string with entries in the Excel sheet
                row = find(com == 1); %Get Row number
                app.camera = cell2mat([data(row+1,1) data(row+1,2) data(row+1,3)]);
                app.cameraAdaptor = cell2mat(data(row+2,1));
                app.cameraName = cell2mat(data(row+2,2));
                app.cameraFormat = cell2mat(data(row+2,3));
                app.cameraID = cell2mat(data(row+2,4));
                strData = 'Done.';
            else
                strData = 'Failed.';
            end
            app.OutputTextArea.Value = sprintf('%s\t%s\n%s\t%s\n%s',dialog1,strDAQ,dialog2,strData,dialog3);
            %---------------------------------------------------%
            % Add the gantry conroller Matlab library to path,
            % first checking if directories for Aerotech/A3200 exists
            tmp1 = sprintf('%s%s%s',app.folder,locker,'\AerotechFunctions');
            tmp2 = 'C:\Program Files (x86)\Aerotech\A3200\Matlab';
            
            if (exist(tmp1,'dir') == 7)
                tmp = tmp1;
                tmp1 = 1;  
                tmp2 = 1;
            elseif (exist(tmp2,'dir') == 7)
                tmp = tmp2;
                tmp1 = 1;
                tmp2 = 1;
            end
            if (tmp1 && tmp2)
                arch = computer('arch');
                if(strcmp(arch, 'win32'))
                    addpath(sprintf('%s\\x86',tmp));
                elseif(strcmp(arch, 'win64'))
                    addpath(sprintf('%s\\x64',tmp));
                end
            
                app.handle = A3200Connect;
                
                % Set up acceleration ramp type to SINUSOIDAL
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.X, 2);
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.Y, 2);
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.Z, 2);
                A3200MotionSetupRampTypeAxis(app.handle, app.task, app.U, 2);
                % Set up acceleration ramp mode to TIME
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.X, 1);
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.Y, 1);
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.Z, 1);
                A3200MotionSetupRampModeAxis(app.handle, app.task, app.U, 1);
                % Set up acceleration ramp time to 'accelTime' seconds
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.X, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Y, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.Z, app.accelTime);
                A3200MotionSetupRampTimeAxis(app.handle, app.task, app.U, app.accelTime);
                
                % Stop motion if any present
                A3200MotionAbort(app.handle, app.allAxes);
                pause(0.1);
                
                app.gantryFlag = 1;
                strGantry = 'Done.';
            else
                strGantry = 'Failed.';
            end
            
            app.OutputTextArea.Value = sprintf('%s\t%s\n%s\t%s\n%s\t%s\n%s',dialog1,strDAQ,dialog2,strData,dialog3,strGantry,dialog4);
            %---------------------------------------------------%
            % Open camera view as separate window
            screenSize = get(0,'ScreenSize');
            app.screen = figure('name', 'Camera View', 'NumberTitle', 'off');
            width = app.screen.Position(3);
            height = app.screen.Position(4);
            app.screen.Position = [(screenSize(3)-width) (screenSize(4)-height)/2 width height];
            %---------------------------------------------------%
            % Open and intitialize camera
            imshow(zeros(1374,1792), 'InitialMagnification',app.scale);
            
            strCamera = 'Failed';
            % Check if video device has been previously found; if not, find it
            out = 1;
            if (~app.cameraID)
                out = formatCamera(app);
            end
            if (out)
                camOn(app,-2);
                strCamera = 'Done.';
            end
            
            % Enable top drop-down menus
            app.FileMenu.Enable = 'on';
            app.AccountMenu.Enable = 'on';
            app.EmergencyStateMenu.Enable = 'on';
            app.OutputTextArea.Value = sprintf('%s\t%s\n%s\t%s\n%s\t%s\n%s\t%s\n\nReady!',dialog1,strDAQ,dialog2,strData,dialog3,strGantry,dialog4,strCamera);
            %%----------------------------------------------------------------------------------------------------------------------------------------------------%%
        end

        % Callback function: ExitMenu, ModulePlacementUIFigure
        function ModulePlacementUIFigureCloseRequest(app, event)
            % Stop any motion before stopping/closing the app
            if (app.gantryFlag)
                A3200MotionAbort(app.handle, app.allAxes);
            end
            
            % Turn off NI controller signals (if any are on)
            if (app.daqFlag)
                app.port = zeros(1,app.num);
                outputSingleScan(app.s,app.port);
            end
            
            % Close everything!
            if (ishandle(app.tabView))
                delete(app.tabView); % Close top-view fidure (if open)
            end
            delete(app.cam);    % Stop the camera
            delete(app.screen); % Close camera figure
            delete(findall(0)); % Close any leftover figures
            delete(app)         % Close GUI
        end

        % Button pushed function: X_Home
        function X_HomeButtonPushed(app, event)
            Home(app,'X');
        end

        % Button pushed function: Y_Home
        function Y_HomeButtonPushed(app, event)
            Home(app,'Y');
        end

        % Button pushed function: Z_Home
        function Z_HomeButtonPushed(app, event)
            Home(app,'Z');
        end

        % Button pushed function: U_Home
        function U_HomeButtonPushed(app, event)
            Home(app,'U');
        end

        % Button pushed function: X_Enable
        function X_EnableButtonPushed(app, event)
            Enable(app,'X');
        end

        % Button pushed function: Y_Enable
        function Y_EnableButtonPushed(app, event)
            Enable(app,'Y');
        end

        % Button pushed function: Z_Enable
        function Z_EnableButtonPushed(app, event)
            Enable(app,'Z');
        end

        % Button pushed function: U_Enable
        function U_EnableButtonPushed(app, event)
            Enable(app,'U');
        end

        % Button pushed function: PetalButton
        function PetalButtonPushed(app, event)
            findPetal(app);
        end

        % Button pushed function: PlaceButton
        function PlaceButtonPushed(app, event)
            Place(app, '');
        end

        % Button pushed function: GlueButton
        function GlueButtonPushed(app, event)
            Glue(app, app.ModuleDropDown.Value);
            if (app.port(21) == 1) % in case glue is stuck in 'on' state, turn off
                app.port(21) = 0;
                outputSingleScan(app.s,app.port);
            end
            
            % Move to safe Z height
            motion = MoveZ(app, 50, -1);
            if (~motion)
                return;
            end
            
            app.PlaceButton.Enable = 'on';
            app.GlueButton.Enable = 'on';
            app.ModuleDropDown.Enable = 'on';
        end

        % Button pushed function: StopMotionButton
        function StopMotionButtonPushed(app, event)
            app.motionStop = 1;
        end

        % Button pushed function: HomeAllButton
        function HomeAllButtonPushed(app, event)
            Home(app,'all');
        end

        % Button pushed function: gantryViewButton
        function gantryViewButtonPushed(app, event)
            gantryView(app);
        end

        % Button pushed function: AXISButton
        function AXISButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.AXIS))
                Light(app, 'Axis',0);
            else
                Light(app,'Axis',1);
            end
            % Pause for camera to adjust to light, then update camera view
            pause(1);
            Collect(app,1);
        end

        % Button pushed function: AbsButton_X
        function AbsButton_XPushed(app, event)
            MoveTo(app, app.AbsStepSize_X.Value, 1, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: AbsButton_Y
        function AbsButton_YPushed(app, event)
            MoveTo(app, -1, app.AbsStepSize_Y.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: AbsButton_Z
        function AbsButton_ZPushed(app, event)
            MoveZ(app, app.AbsStepSize_Z.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: AbsButton_U
        function AbsButton_UPushed(app, event)
            MoveU(app, app.AbsStepSize_U.Value, app.VelocitymmsEditField.Value/4);
        end

        % Button pushed function: IncButton_Xn
        function IncButton_XnPushed(app, event)
            MoveBy(app, 'x', -app.IncStepSize_X.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: IncButton_Yn
        function IncButton_YnPushed(app, event)
            MoveBy(app, 'y', -app.IncStepSize_Y.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: IncButton_Zn
        function IncButton_ZnPushed(app, event)
            MoveBy(app, 'z', -app.IncStepSize_Z.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            MoveBy(app, 'u', -app.IncStepSize_U.Value, app.VelocitymmsEditField.Value/4);
        end

        % Button pushed function: IncButton_Xp
        function IncButton_XpPushed(app, event)
            MoveBy(app, 'x', app.IncStepSize_X.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: IncButton_Yp
        function IncButton_YpPushed(app, event)
            MoveBy(app, 'y', app.IncStepSize_Y.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: IncButton_Zp
        function IncButton_ZpPushed(app, event)
            MoveBy(app, 'z', app.IncStepSize_Z.Value, app.VelocitymmsEditField.Value);
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            MoveBy(app, 'u', app.IncStepSize_U.Value, app.VelocitymmsEditField.Value/4);
        end

        % Value changed function: ControlCheckBox
        function ControlCheckBoxValueChanged(app, event)
            value = app.ControlCheckBox.Value;
            
            if (value) % if 'checked'
                for i = 1:size(app.ManualDAQTab.Children,1)
                    if (~strcmp(app.ManualDAQTab.Children(i).Text,'Control'))
                        app.ManualDAQTab.Children(i).Enable = 'on';
                    end
                end
            else       % if 'un-checked'
                for i = 1:size(app.ManualDAQTab.Children,1)
                    if (~strcmp(app.ManualDAQTab.Children(i).Text,'Control'))
                        app.ManualDAQTab.Children(i).Enable = 'off';
                        app.ManualDAQTab.Children(i).BackgroundColor = [0.96 0.96 0.96];
                    end
                end
                
                % Turn off all outputs
                app.port = zeros(1,app.num);
                outputSingleScan(app.s,app.port);
            end
        end

        % Button pushed function: GantryVACButton
        function GantryVACButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.VAC1))
                Vacuum(app, 'gantry',0);
            else
                Vacuum(app, 'gantry',1);
            end

        end

        % Button pushed function: LED1Button
        function LED1ButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.LED1))
                Light(app, 'LED1',0);
            else
                Light(app, 'LED1',1);
            end
            % Pause for camera to adjust to light, then update camera view
            pause(1);
            Collect(app,1);
        end

        % Button pushed function: LED2Button
        function LED2ButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.LED2))
                Light(app, 'LED2',0);
            else
                Light(app, 'LED2',1);
            end
            % Pause for camera to adjust to light, then update camera view
            pause(1);
            Collect(app,1);
        end

        % Button pushed function: LED3Button
        function LED3ButtonPushed(app, event)
            % Check if light is on,
            % if it is, turn it off - otherwise turn it on
            if (app.port(app.LED3))
                Light(app, 'LED3',0);
            else
                Light(app, 'LED3',1);
            end
            % Pause for camera to adjust to light, then update camera view
            pause(1);
            Collect(app,1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check the state of the 'Save' button
            % If it is set to 'Save', save the output text/log file with the name entered;
            % Otherwise, enable the Petal ID edit field and save a new file/name.
            if (strcmp(app.SaveButton.Text,'Save'))
                str = app.PetalIDEditField.Value;
                app.outputFileTmp = sprintf('%s%s.txt', app.outputFile,str);
                
                while(1)
                    % Check if the filename already exists
                    if isfile(app.outputFileTmp)
                        % If it exists, ask user to change name or overwrite
                        msg = sprintf('File "%s" already exists;\nDid you want to overwrite it?',str);
                        title = 'Confirm Petal ID';
                        selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                            'Options',{'Yes','No'},...
                            'DefaultOption',2,'CancelOption',2);
                        if  (strcmp(selection,'No'))
                            % If user selects 'No', return from function:
                            return;
                        else
                            % If user selects 'Yes', break from loop:
                            break;
                        end
                    else
                        % If file does not exist, break from loop and create it:
                        break;
                    end
                end
                
                % Create the actual log file based on above determined name
                fID = fopen(app.outputFileTmp,'wt');
                fprintf(fID, 'User: %s\n', app.username);
                fprintf(fID, 'Petal ID: %s\n',str);
                dayTime = datetime('now');
                dayTime = datestr(dayTime);
                fprintf(fID, '%s\n\n',dayTime);
                fprintf(fID, '------------------------------------------------\n\n');
                fclose(fID);
                
                % Output log data so far
                printOutput(app);
                pause(0.25);
                
                app.PetalIDEditField.Enable = 'off';
                app.PetalIDLabel.Enable = 'off';
                app.SaveButton.Text = 'Reset';
                app.PetalButton.Enable = 'on';
            else
                app.PetalIDEditField.Enable = 'on';
                app.PetalIDLabel.Enable = 'on';
                app.SaveButton.Text = 'Save';
                app.PetalButton.Enable = 'off';
            end
        end

        % Menu selected function: LoginMenu
        function LoginMenuSelected(app, event)
            if (strcmp(app.LoginMenu.Text, 'Login'))
                % Read in and initialize data for usernames and passwords
                [~,~,data] = xlsread(app.userFile);
                
                userList = cell(size(data,1),1);
                passList = cell(size(data,1)-1,1);
                dataList = zeros(1,size(data,1)-1);
                
                % Manually enter first entry due to starting with second row (ignoring header lines in excel)
                userList(1) = {'Select User'};
                dataList(1) = 1;
                for i = 2:size(data,1)
                    userList(i) = data(i,1);
                    passList(i-1) = data(i,2);
                    dataList(i) = i;
                end
                pass = '';
                passLen = 0;
                
                % Dimensions of app
                left = app.ModulePlacementUIFigure.Position(1);
                bottom = app.ModulePlacementUIFigure.Position(2);
                width = app.ModulePlacementUIFigure.Position(3);
                height = app.ModulePlacementUIFigure.Position(4);
                
                % Dimensions of Login Menu
                newLeft = left + width/4;
                newBottom = bottom + height/2 - 104;
                newWidth = width/2;
                newHeight = 208;
                
                % Create pop-up window for logging in
                login = uifigure('name','Login', 'Position', [newLeft newBottom newWidth newHeight]);
                dropdown = uidropdown(login, 'Position', [(newWidth/6) (3*newHeight/4) (2*newWidth/3) (newHeight/8)], 'Items', userList, 'ItemsData', dataList, 'Value', 1);
                uibutton(login, 'text','Login', 'Position', [(newWidth/6) (newHeight/8) (newWidth/4) (newHeight/4)],'ButtonPushedFcn', {@PushLogin_CallBack});
                uibutton(login, 'text','Cancel', 'Position', [(7*newWidth/12) (newHeight/8) (newWidth/4) (newHeight/4)],'ButtonPushedFcn', {@PushCancel_CallBack});
                editfield = uieditfield(login, 'Position', [(newWidth/6) (newHeight/2) (2*newWidth/3) (newHeight/8)],'ValueChangingFcn',{@ValueChanging_Callback});
                
                pass = editfield.UserData;
            else
                % Logout and gray out placement menus/buttons
                % app.menu... Enable = 'off';
                app.LoginMenu.Text = 'Login';
                app.username = '';
                
                % Output to log file when logout occurred
                str = 'Logged out.';
                writeOutput(app, {str});
                
                % Disable buttons/menus
                controlEnable(app, 'off');
                
                % Reset output file name to general gantry output file
                app.outputFileTmp = sprintf('%s%s',app.folder,'\Output\GantryLog.txt');
            end
            %%%%%%%%%%%%%%%%%%%%%%%
            % Function used when entering password
            function ValueChanging_Callback(editfield, data)
                % Update password with new character entered
                if (size(data.Value,2) > passLen)
                    pass(size(data.Value,2)) = data.Value(size(data.Value,2));
                    
                    editfield.UserData = pass;
                    mimic = zeros(1,size(pass,2));
                    for i = 1:size(pass,2)
                        mimic(i) = '*';
                    end
                    editfield.Value = char(mimic);
                    
                    passLen = passLen + 1;
                else % backspace clears entire entry
                    passLen = 0;
                    pass = '';
                    editfield.Value = '';
                    editfield.UserData = '';
                end
            end
            
            % Function for when 'Login' button is pressed
            % (checks if login password is correct...)
            function PushLogin_CallBack(~, ~)
                % Login and enable placement menus/buttons
                userChar = char(editfield.UserData); % Entered password
                passChar = char(cell2mat(passList(dropdown.Value - 1))); % Password to compare to
                if (strcmp(userChar,passChar))
                    app.username = char(cell2mat(userList(dropdown.Value)));
                    app.OutputTextArea.Value = sprintf('Logged in as: %s',app.username);
                    
                    delete(login);
                    % app.menu... Enable = 'on';
                    app.LoginMenu.Text = 'Logout';
                    % Enable all axes
                    Enable(app,'all');
                    Collect(app,1);
                    
                    % Create the general log file with user information
                    fID = fopen(app.outputFileTmp,'wt');
                    fprintf(fID, 'User: %s\n', app.username);
                    dayTime = datetime('now');
                    dayTime = datestr(dayTime);
                    fprintf(fID, '%s\n\n',dayTime);
                    fprintf(fID, '------------------------------------------------\n\n');
                    fclose(fID);
                    
                    % Output log data so far
                    printOutput(app);
                    pause(0.25);
                    
                    % Ask user to home all axes before controlling gantry
                    msg = 'Home all axes?';
                    title = 'Home Axes';
                    selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                        'Options',{'Yes','No'},...
                        'DefaultOption',1,'CancelOption',2);
                    if (strcmp(selection,'Yes'))
                        % Home all axes
                        Home(app,'all');
                    end
                    
                    % Enable buttons/menus
                    controlEnable(app, 'on');
                else
                    % If password is incorrect, reset password text and wait for another entry/button press
                    editfield.Value = '';
                    pass = '';
                end
            end
            % Function if 'Cancel' is pressed
            % (closes dialog)
            function PushCancel_CallBack(~, ~)
                delete(login);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            % Check which side of the petal to display
            if (strcmp(value,'Side A'))
                app.petalImg = imread('PetalImgA_Edit.png');
            else % (strcmp(value,'Side B'))
                app.petalImg = imread('PetalImgB_Edit.png');
            end
            
            imshow(app.petalImg,'Parent',app.PetalPic);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            Place(app, 'calibrate');
        end

        % Menu selected function: FormatMenu
        function FormatMenuSelected(app, event)
            formatCamera(app);
            camOn(app,-2);
        end

        % Menu selected function: SafeMoveMenu
        function SafeMoveMenuSelected(app, event)
            % Alert user before moving:
            msg = 'Do you want to move gantry to safe location?';
            title = 'Move to safety';
            selection = uiconfirm(app.ModulePlacementUIFigure,msg,title,...
                'Options',{'Yes','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            if  (selection == 0)
                % if user selects 'cancel', return from function
                return;
            end
            
            % Check if gantry is holding anything:
            % if so, % Move gantry head to max height and then move in Y
            % then X then move down and turn off all DAQ output channels.
            if (app.port(1))
                MoveZ(app, 0, -1);
                MoveTo(app, -1,0, -1);
                MoveTo(app, 0,1, -1);
                MoveZ(app, -ZHEIGHT, 25.4);
                
                app.port = zeros(1,app.num);
                outputSingleScan(app.s,app.port);
            end
            
            % Otherwise, just home all axes
            Home(app, 'all');
        end

        % Menu selected function: SaveImageMenu
        function SaveImageMenuSelected(app, event)
            idx = 1;
            while (1)
                fileName = sprintf('%sImage_%d.png',app.imageOutDirectory,idx);
                if (~exist(fileName,'file'))
                    saveas(app.screen,fileName,'png');
                    break;
                else
                    idx = idx + 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModulePlacementUIFigure = uifigure('Visible', 'off');
            app.ModulePlacementUIFigure.Name = 'Module Placement';
            app.ModulePlacementUIFigure.CloseRequestFcn = createCallbackFcn(app, @ModulePlacementUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ModulePlacementUIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.SaveStateMenu = uimenu(app.FileMenu);
            app.SaveStateMenu.Text = 'Save State';
            app.LoadStateMenu = uimenu(app.FileMenu);
            app.LoadStateMenu.Enable = 'off';
            app.LoadStateMenu.Text = 'Load State';
            app.CameraMenu = uimenu(app.FileMenu);
            app.CameraMenu.Text = 'Camera';
            app.SaveImageMenu = uimenu(app.CameraMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveImageMenu.Text = 'Save Image';
            app.FormatMenu = uimenu(app.CameraMenu);
            app.FormatMenu.MenuSelectedFcn = createCallbackFcn(app, @FormatMenuSelected, true);
            app.FormatMenu.Text = 'Format';
            app.EmergencyStateMenu = uimenu(app.FileMenu);
            app.EmergencyStateMenu.Text = 'Emergency State';
            app.SafeMoveMenu = uimenu(app.EmergencyStateMenu);
            app.SafeMoveMenu.MenuSelectedFcn = createCallbackFcn(app, @SafeMoveMenuSelected, true);
            app.SafeMoveMenu.Tooltip = {'Homes gantry.'; '* If vacuum is present'; ' moves to back-right of gantry and carefully places piece before homing.'};
            app.SafeMoveMenu.Text = 'Safe Move';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ModulePlacementUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.AccountMenu = uimenu(app.ModulePlacementUIFigure);
            app.AccountMenu.Enable = 'off';
            app.AccountMenu.Text = 'Account';
            app.LoginMenu = uimenu(app.AccountMenu);
            app.LoginMenu.MenuSelectedFcn = createCallbackFcn(app, @LoginMenuSelected, true);
            app.LoginMenu.Text = 'Login';
            app.PetalPic = uiaxes(app.ModulePlacementUIFigure);
            app.PetalPic.XTick = [];
            app.PetalPic.YTick = [];
            app.Label = uilabel(app.ModulePlacementUIFigure);
            app.Label.Text = '____________________________________________________________________________________________________';
            app.MovementPanel = uipanel(app.ModulePlacementUIFigure);
            app.MovementPanel.Title = 'Movement';
            app.StopMotionButton = uibutton(app.MovementPanel, 'push');
            app.StopMotionButton.ButtonPushedFcn = createCallbackFcn(app, @StopMotionButtonPushed, true);
            app.StopMotionButton.Enable = 'off';
            app.StopMotionButton.Text = 'Stop Motion';
            app.TabGroup = uitabgroup(app.MovementPanel);
            app.AbsoluteTab = uitab(app.TabGroup);
            app.AbsoluteTab.Title = 'Absolute';
            app.AbsStepSize_X = uieditfield(app.AbsoluteTab, 'numeric');
            app.AbsStepSize_X.Limits = [0 999];
            app.AbsStepSize_X.Enable = 'off';
            app.AbsStepSize_Y = uieditfield(app.AbsoluteTab, 'numeric');
            app.AbsStepSize_Y.Limits = [-999 0];
            app.AbsStepSize_Y.Enable = 'off';
            app.AbsStepSize_Z = uieditfield(app.AbsoluteTab, 'numeric');
            app.AbsStepSize_Z.Limits = [-125 0];
            app.AbsStepSize_Z.Enable = 'off';
            app.AbsStepSize_U = uieditfield(app.AbsoluteTab, 'numeric');
            app.AbsStepSize_U.Limits = [-360 360];
            app.AbsStepSize_U.Enable = 'off';
            app.PositionmmLabel = uilabel(app.AbsoluteTab);
            app.PositionmmLabel.Enable = 'off';
            app.PositionmmLabel.Text = 'Position (mm)';
            app.AbsButton_X = uibutton(app.AbsoluteTab, 'push');
            app.AbsButton_X.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_XPushed, true);
            app.AbsButton_X.Enable = 'off';
            app.AbsButton_X.Text = 'X';
            app.AbsButton_Y = uibutton(app.AbsoluteTab, 'push');
            app.AbsButton_Y.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_YPushed, true);
            app.AbsButton_Y.Enable = 'off';
            app.AbsButton_Y.Text = 'Y';
            app.AbsButton_Z = uibutton(app.AbsoluteTab, 'push');
            app.AbsButton_Z.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_ZPushed, true);
            app.AbsButton_Z.Enable = 'off';
            app.AbsButton_Z.Text = 'Z';
            app.AbsButton_U = uibutton(app.AbsoluteTab, 'push');
            app.AbsButton_U.ButtonPushedFcn = createCallbackFcn(app, @AbsButton_UPushed, true);
            app.AbsButton_U.Enable = 'off';
            app.AbsButton_U.Text = 'U';
            app.PositiondegLabel = uilabel(app.AbsoluteTab);
            app.PositiondegLabel.Enable = 'off';
            app.PositiondegLabel.Text = 'Position (deg)';
            app.VelocitymmsEditFieldLabel = uilabel(app.AbsoluteTab);
            app.VelocitymmsEditFieldLabel.Enable = 'off';
            app.VelocitymmsEditFieldLabel.Text = 'Velocity (mm/s)';
            app.VelocitymmsEditField = uieditfield(app.AbsoluteTab, 'numeric');
            app.VelocitymmsEditField.Limits = [0 150];
            app.VelocitymmsEditField.Enable = 'off';
            app.VelocitymmsEditField.Value = 100;
            app.IncrementalTab = uitab(app.TabGroup);
            app.IncrementalTab.Title = 'Incremental';
            app.IncStepSize_X = uieditfield(app.IncrementalTab, 'numeric');
            app.IncStepSize_X.Limits = [0 1000];
            app.IncStepSize_X.Enable = 'off';
            app.IncStepSize_X.Value = 0.25;
            app.IncStepSize_Y = uieditfield(app.IncrementalTab, 'numeric');
            app.IncStepSize_Y.Limits = [0 1000];
            app.IncStepSize_Y.Enable = 'off';
            app.IncStepSize_Y.Value = 0.25;
            app.IncStepSize_Z = uieditfield(app.IncrementalTab, 'numeric');
            app.IncStepSize_Z.Limits = [0 120];
            app.IncStepSize_Z.Enable = 'off';
            app.IncStepSize_Z.Value = 0.025;
            app.IncStepSize_U = uieditfield(app.IncrementalTab, 'numeric');
            app.IncStepSize_U.Limits = [-180 180];
            app.IncStepSize_U.Enable = 'off';
            app.StepSizemmLabel = uilabel(app.IncrementalTab);
            app.StepSizemmLabel.Enable = 'off';
            app.StepSizemmLabel.Text = 'Step Size (mm)';
            app.IncButton_Xp = uibutton(app.IncrementalTab, 'push');
            app.IncButton_Xp.ButtonPushedFcn = createCallbackFcn(app, @IncButton_XpPushed, true);
            app.IncButton_Xp.Enable = 'off';
            app.IncButton_Xp.Text = 'X+';
            app.IncButton_Yp = uibutton(app.IncrementalTab, 'push');
            app.IncButton_Yp.ButtonPushedFcn = createCallbackFcn(app, @IncButton_YpPushed, true);
            app.IncButton_Yp.Enable = 'off';
            app.IncButton_Yp.Text = 'Y+';
            app.IncButton_Zp = uibutton(app.IncrementalTab, 'push');
            app.IncButton_Zp.ButtonPushedFcn = createCallbackFcn(app, @IncButton_ZpPushed, true);
            app.IncButton_Zp.Enable = 'off';
            app.IncButton_Zp.Text = 'Z+';
            app.CWButton = uibutton(app.IncrementalTab, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Enable = 'off';
            app.CWButton.Text = 'CW';
            app.IncButton_Xn = uibutton(app.IncrementalTab, 'push');
            app.IncButton_Xn.ButtonPushedFcn = createCallbackFcn(app, @IncButton_XnPushed, true);
            app.IncButton_Xn.Enable = 'off';
            app.IncButton_Xn.Text = 'X-';
            app.IncButton_Yn = uibutton(app.IncrementalTab, 'push');
            app.IncButton_Yn.ButtonPushedFcn = createCallbackFcn(app, @IncButton_YnPushed, true);
            app.IncButton_Yn.Enable = 'off';
            app.IncButton_Yn.Text = 'Y-';
            app.IncButton_Zn = uibutton(app.IncrementalTab, 'push');
            app.IncButton_Zn.ButtonPushedFcn = createCallbackFcn(app, @IncButton_ZnPushed, true);
            app.IncButton_Zn.Enable = 'off';
            app.IncButton_Zn.Text = 'Z-';
            app.CCWButton = uibutton(app.IncrementalTab, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Enable = 'off';
            app.CCWButton.Text = 'CCW';
            app.StepSizedegLabel = uilabel(app.IncrementalTab);
            app.StepSizedegLabel.Enable = 'off';
            app.StepSizedegLabel.Text = 'Step Size (deg)';
            app.GraphicalTab = uitab(app.TabGroup);
            app.GraphicalTab.Title = 'Graphical';
            app.gantryViewButton = uibutton(app.GraphicalTab, 'push');
            app.gantryViewButton.ButtonPushedFcn = createCallbackFcn(app, @gantryViewButtonPushed, true);
            app.gantryViewButton.Enable = 'off';
            app.gantryViewButton.Text = 'Open Table View';
            app.TabGroup2 = uitabgroup(app.ModulePlacementUIFigure);
            app.ModuleLoadingTab = uitab(app.TabGroup2);
            app.ModuleLoadingTab.Title = 'Module Loading';
            app.PetalButton = uibutton(app.ModuleLoadingTab, 'push');
            app.PetalButton.ButtonPushedFcn = createCallbackFcn(app, @PetalButtonPushed, true);
            app.PetalButton.Enable = 'off';
            app.PetalButton.Text = 'Petal';
            app.ModuleDropDownLabel = uilabel(app.ModuleLoadingTab);
            app.ModuleDropDownLabel.Enable = 'off';
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.ModuleLoadingTab);
            app.ModuleDropDown.Items = {'R0', 'R1', 'R2', 'R3a', 'R3b', 'R4a', 'R4b', 'R5a', 'R5b'};
            app.ModuleDropDown.Enable = 'off';
            app.ModuleDropDown.Value = 'R0';
            app.PlaceButton = uibutton(app.ModuleLoadingTab, 'push');
            app.PlaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceButtonPushed, true);
            app.PlaceButton.Enable = 'off';
            app.PlaceButton.Text = 'Place';
            app.PetalLamp = uilamp(app.ModuleLoadingTab);
            app.GlueButton = uibutton(app.ModuleLoadingTab, 'push');
            app.GlueButton.ButtonPushedFcn = createCallbackFcn(app, @GlueButtonPushed, true);
            app.GlueButton.Enable = 'off';
            app.GlueButton.Text = 'Glue';
            app.CalibrateButton = uibutton(app.ModuleLoadingTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.ModuleSurveyingTab = uitab(app.TabGroup2);
            app.ModuleSurveyingTab.Title = 'Module Surveying';
            app.ModuleDropDown_2Label = uilabel(app.ModuleSurveyingTab);
            app.ModuleDropDown_2Label.Enable = 'off';
            app.ModuleDropDown_2Label.Text = 'Module';
            app.ModuleDropDown_2 = uidropdown(app.ModuleSurveyingTab);
            app.ModuleDropDown_2.Items = {'R0', 'R1', 'R2', 'R3a', 'R3b', 'R4a', 'R4b', 'R5a', 'R5b'};
            app.ModuleDropDown_2.Enable = 'off';
            app.ModuleDropDown_2.Value = 'R0';
            app.MoveButton = uibutton(app.ModuleSurveyingTab, 'state');
            app.MoveButton.Enable = 'off';
            app.MoveButton.Text = 'Move';
            app.NextButton = uibutton(app.ModuleSurveyingTab, 'state');
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.ManualDAQTab = uitab(app.TabGroup2);
            app.ManualDAQTab.Title = 'Manual DAQ';
            app.AXISButton = uibutton(app.ManualDAQTab, 'push');
            app.AXISButton.ButtonPushedFcn = createCallbackFcn(app, @AXISButtonPushed, true);
            app.AXISButton.Enable = 'off';
            app.AXISButton.Text = 'AXIS';
            app.LED1Button = uibutton(app.ManualDAQTab, 'push');
            app.LED1Button.ButtonPushedFcn = createCallbackFcn(app, @LED1ButtonPushed, true);
            app.LED1Button.Enable = 'off';
            app.LED1Button.Text = 'LED 1';
            app.LED2Button = uibutton(app.ManualDAQTab, 'push');
            app.LED2Button.ButtonPushedFcn = createCallbackFcn(app, @LED2ButtonPushed, true);
            app.LED2Button.Enable = 'off';
            app.LED2Button.Text = 'LED 2';
            app.LED3Button = uibutton(app.ManualDAQTab, 'push');
            app.LED3Button.ButtonPushedFcn = createCallbackFcn(app, @LED3ButtonPushed, true);
            app.LED3Button.Enable = 'off';
            app.LED3Button.Text = 'LED 3';
            app.GantryVACButton = uibutton(app.ManualDAQTab, 'push');
            app.GantryVACButton.ButtonPushedFcn = createCallbackFcn(app, @GantryVACButtonPushed, true);
            app.GantryVACButton.Enable = 'off';
            app.GantryVACButton.Text = 'Gantry VAC';
            app.ControlCheckBox = uicheckbox(app.ManualDAQTab);
            app.ControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlCheckBoxValueChanged, true);
            app.ControlCheckBox.Enable = 'off';
            app.ControlCheckBox.Text = 'Control';
            app.ChuckVACButton = uibutton(app.ManualDAQTab, 'push');
            app.ChuckVACButton.Enable = 'off';
            app.ChuckVACButton.Text = 'Chuck VAC';
            app.AxisPanel = uipanel(app.ModulePlacementUIFigure);
            app.AxisPanel.Title = 'Axis';
            app.XLabel = uilabel(app.AxisPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.AxisPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.AxisPanel);
            app.ZLabel.Text = 'Z';
            app.ULabel = uilabel(app.AxisPanel);
            app.ULabel.Text = 'U';
            app.U_Home = uibutton(app.AxisPanel, 'push');
            app.U_Home.ButtonPushedFcn = createCallbackFcn(app, @U_HomeButtonPushed, true);
            app.U_Home.Enable = 'off';
            app.U_Home.Text = '';
            app.Z_Home = uibutton(app.AxisPanel, 'push');
            app.Z_Home.ButtonPushedFcn = createCallbackFcn(app, @Z_HomeButtonPushed, true);
            app.Z_Home.Enable = 'off';
            app.Z_Home.Text = '';
            app.Y_Home = uibutton(app.AxisPanel, 'push');
            app.Y_Home.ButtonPushedFcn = createCallbackFcn(app, @Y_HomeButtonPushed, true);
            app.Y_Home.Enable = 'off';
            app.Y_Home.Text = '';
            app.X_Home = uibutton(app.AxisPanel, 'push');
            app.X_Home.ButtonPushedFcn = createCallbackFcn(app, @X_HomeButtonPushed, true);
            app.X_Home.Enable = 'off';
            app.X_Home.Text = '';
            app.U_Enable = uibutton(app.AxisPanel, 'push');
            app.U_Enable.ButtonPushedFcn = createCallbackFcn(app, @U_EnableButtonPushed, true);
            app.U_Enable.Enable = 'off';
            app.U_Enable.Text = '';
            app.Z_Enable = uibutton(app.AxisPanel, 'push');
            app.Z_Enable.ButtonPushedFcn = createCallbackFcn(app, @Z_EnableButtonPushed, true);
            app.Z_Enable.Enable = 'off';
            app.Z_Enable.Text = '';
            app.Y_Enable = uibutton(app.AxisPanel, 'push');
            app.Y_Enable.ButtonPushedFcn = createCallbackFcn(app, @Y_EnableButtonPushed, true);
            app.Y_Enable.Enable = 'off';
            app.Y_Enable.Text = '';
            app.X_Enable = uibutton(app.AxisPanel, 'push');
            app.X_Enable.ButtonPushedFcn = createCallbackFcn(app, @X_EnableButtonPushed, true);
            app.X_Enable.Enable = 'off';
            app.X_Enable.Text = '';
            app.VelocityFeedbackPanel = uipanel(app.ModulePlacementUIFigure);
            app.VelocityFeedbackPanel.Title = 'Velocity Feedback';
            app.TextArea_XV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_YV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_ZV = uitextarea(app.VelocityFeedbackPanel);
            app.TextArea_UV = uitextarea(app.VelocityFeedbackPanel);
            app.mmsLabel = uilabel(app.VelocityFeedbackPanel);
            app.mmsLabel.Text = 'mm/s';
            app.mmsLabel_2 = uilabel(app.VelocityFeedbackPanel);
            app.mmsLabel_2.Text = 'mm/s';
            app.mmsLabel_3 = uilabel(app.VelocityFeedbackPanel);
            app.mmsLabel_3.Text = 'mm/s';
            app.degsLabel = uilabel(app.VelocityFeedbackPanel);
            app.degsLabel.Text = 'deg/s';
            app.PositionFeedbackPanel = uipanel(app.ModulePlacementUIFigure);
            app.PositionFeedbackPanel.Title = 'Position Feedback';
            app.TextArea_XP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_YP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_ZP = uitextarea(app.PositionFeedbackPanel);
            app.TextArea_UP = uitextarea(app.PositionFeedbackPanel);
            app.mmLabel = uilabel(app.PositionFeedbackPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.PositionFeedbackPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.PositionFeedbackPanel);
            app.mmLabel_3.Text = 'mm';
            app.degLabel = uilabel(app.PositionFeedbackPanel);
            app.degLabel.Text = 'deg';
            app.HomeAllButton = uibutton(app.ModulePlacementUIFigure, 'push');
            app.HomeAllButton.ButtonPushedFcn = createCallbackFcn(app, @HomeAllButtonPushed, true);
            app.HomeAllButton.Enable = 'off';
            app.HomeAllButton.Text = 'Home All';
            app.Switch = uiswitch(app.ModulePlacementUIFigure, 'slider');
            app.Switch.Items = {'Side B', 'Side A'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Side A';
            app.PetalIDLabel = uilabel(app.ModulePlacementUIFigure);
            app.PetalIDLabel.Enable = 'off';
            app.PetalIDLabel.Text = 'Petal ID:';
            app.PetalIDEditField = uieditfield(app.ModulePlacementUIFigure, 'text');
            app.PetalIDEditField.Enable = 'off';
            app.PetalIDEditField.Value = 'PT0001';
            app.SaveButton = uibutton(app.ModulePlacementUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.TabGroup3 = uitabgroup(app.ModulePlacementUIFigure);
            app.OutputTab = uitab(app.TabGroup3);
            app.OutputTab.Title = 'Output';
            app.OutputTextArea = uitextarea(app.OutputTab);
            % Show the figure after all components are created
            app.ModulePlacementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModulePlacement_MATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModulePlacementUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModulePlacementUIFigure)
        end
    end
end