classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ActionsButtonGroup        matlab.ui.container.ButtonGroup
        LocatePetalButton         matlab.ui.control.ToggleButton
        LocateHybridsButton       matlab.ui.control.ToggleButton
        GluedispensingButton      matlab.ui.control.ToggleButton
        HomingButton              matlab.ui.control.ToggleButton
        MovementButtonGroup       matlab.ui.container.ButtonGroup
        SimulationButton          matlab.ui.control.RadioButton
        ManualButton              matlab.ui.control.RadioButton
        AutoButton                matlab.ui.control.RadioButton
        Button                    matlab.ui.control.RadioButton
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        Z1EditFieldLabel          matlab.ui.control.Label
        Z1EditField               matlab.ui.control.NumericEditField
        Z2EditFieldLabel          matlab.ui.control.Label
        Z2EditField               matlab.ui.control.NumericEditField
        UIAxes2                   matlab.ui.control.UIAxes
        EditFieldLabel            matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        ConnectGantryCheckBox     matlab.ui.control.CheckBox
        ConnectDispenserCheckBox  matlab.ui.control.CheckBox
        ConnectCameraCheckBox     matlab.ui.control.CheckBox
        StopButton                matlab.ui.control.StateButton
        ConnectButton             matlab.ui.control.StateButton
        GoButton                  matlab.ui.control.StateButton
        XEditField_2Label         matlab.ui.control.Label
        XEditField_2              matlab.ui.control.NumericEditField
        YEditField_2Label         matlab.ui.control.Label
        YEditField_2              matlab.ui.control.NumericEditField
        Z1EditField_2Label        matlab.ui.control.Label
        Z1EditField_2             matlab.ui.control.NumericEditField
        Z2EditField_2Label        matlab.ui.control.Label
        GantryMotorsLabel         matlab.ui.control.Label
        Z2EditField_2             matlab.ui.control.NumericEditField
        Lamp_xMotor               matlab.ui.control.Lamp
        Lamp_yMotor               matlab.ui.control.Lamp
        Lamp_z1Motor              matlab.ui.control.Lamp
        Lamp_z2Motor              matlab.ui.control.Lamp
        Lamp_UMotor               matlab.ui.control.Lamp
        EditField_2Label          matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        CurrentPositionLabel      matlab.ui.control.Label
        MoveToLabel               matlab.ui.control.Label
        XSwitchLabel              matlab.ui.control.Label
        XSwitch                   matlab.ui.control.Switch
        YSwitchLabel              matlab.ui.control.Label
        YSwitch                   matlab.ui.control.Switch
        Z1SwitchLabel             matlab.ui.control.Label
        Z1Switch                  matlab.ui.control.Switch
        Z2SwitchLabel             matlab.ui.control.Label
        Z2Switch                  matlab.ui.control.Switch
        AllSwitchLabel            matlab.ui.control.Label
        AllSwitch                 matlab.ui.control.ToggleSwitch
        USwitchLabel              matlab.ui.control.Label
        USwitch                   matlab.ui.control.Switch
    end

    
    properties (Access = private)
        Property % Custom properties
        gantry;
        cam;
        joy;
        fid;
        focus;
        gluing;
        dispenser;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gantry = STAGES(2);
            app.joy = JOYSTICK (app.gantry);
            app.fid=FIDUCIALS(1);
            app.cam= CAMERA(5);
        end

        % Value changed function: XSwitch
        function XSwitchValueChanged(app, event)
            value = app.XSwitch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.X)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.X)
            end
        end

        % Value changed function: YSwitch
        function YSwitchValueChanged(app, event)
            value = app.YSwitch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.Y)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.Y)
            end
        end

        % Value changed function: Z1Switch
        function Z1SwitchValueChanged(app, event)
            value = app.Z1Switch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.Z1)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.Z1)
            end
        end

        % Value changed function: Z2Switch
        function Z2SwitchValueChanged(app, event)
            value = app.Z2Switch.Value;
            switch value
                case 'On'
                    app.gantry.MotorEnable(app.gantry.Z2)
                case 'Off'
                    app.gantry.MotorDisable(app.gantry.Z2)
            end
        end

        % Value changed function: AllSwitch
        function AllSwitchValueChanged(app, event)
            value = app.AllSwitch.Value;
            app.gantry.MotorEnableAll;
            %             app.XSwitch.Value = value;
            %             app.YSwitch.Value = value;
            %             app.Z1Switch.Value = value;
            %             app.Z2Switch.Value = value;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            if value == 1
                app.StopButton.BackgroundColor='r';
                app.gantry.MotionAbortAll();
            else
                app.StopButton.BackgroundColor = 'g';
            end
        end

        % Value changed function: ConnectGantryCheckBox
        function ConnectGantryCheckBoxValueChanged(app, event)
            value = app.ConnectGantryCheckBox.Value;
        end

        % Value changed function: ConnectDispenserCheckBox
        function ConnectDispenserCheckBoxValueChanged(app, event)
            value = app.ConnectDispenserCheckBox.Value;
        end

        % Callback function
        function ConnectJoystickCheckBoxValueChanged(app, event)
            value = app.ConnectJoystickCheckBox.Value;
            if value == 1
                
            else
                app.joy.Disconnect;
            end
        end

        % Value changed function: ConnectCameraCheckBox
        function ConnectCameraCheckBoxValueChanged(app, event)
            value = app.ConnectCameraCheckBox.Value;
            if value == 1
                app.cam= CAMERA(5);
                app.cam.Connect;
                app.cam.DispCam;
            else
                app.cam.Disconnect;
                imaqreset;
            end
        end

        % Callback function
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            switch value
                case 'On'
                    app.ConnectGantryCheckBox.Value = 1;
                    app.ConnectJoystickCheckBox.Value = 1;
                    app.ConnectCameraCheckBox.Value = 1;
                    app.ConnectDispenserCheckBox.Value = 1;
                case 'Off'
                    app.ConnectGantryCheckBox.Value = 0;
                    app.ConnectJoystickCheckBox.Value = 0;
                    app.ConnectCameraCheckBox.Value = 0;
                    app.ConnectDispenserCheckBox.Value = 0;
            end
            %             app.ConnectGantryCheckBox.ValueChanged();
            %             app.ConnectJoystickCheckBox.ValueChanged();
            %             app.ConnectCameraCheckBox.ValueChanged();
            %             app.ConnectDispenserCheckBox.ValueChanged();
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
            if app.ManualButton == 1
                app.joy = JOYSTICK (app.gantry);
                app.joy.Connect;
            else
                app.joy.Disconnect;
            end
            if app.gantry.IsConnected ~= 1
                app.ConnectGantryCheckBox = 0;
            end
            if app.joy.JoystickIsReady ~= 1
                app.ConnectJoystickCheckBox = 0;
            end
            
            if app.cam.IsConnected ~= 1
                app.ConnectCameraCheckBox = 0;
            end
            
            % Updating indicators
            if app.gantry.MotorState(app.gantry.X) == 4194321
                app.Lamp_xMotor.Color = 'g';
                app.XSwitch.Value = 'On';
            else
                app.Lamp_xMotor.Color = 'r';
                app.XSwitch.Value = 'Off';
            end
            
            if app.gantry.MotorState(app.gantry.Y) == 4194321
                app.Lamp_yMotor.Color = 'g';
                app.YSwitch.Value = 'On';
            else
                app.Lamp_yMotor.Color = 'r';
                app.YSwitch.Value = 'Off';
            end
            
            if app.gantry.MotorState(app.gantry.Z1) == 4194321
                app.Lamp_z1Motor.Color = 'g';
                app.Z1Switch.Value = 'On';
            else
                app.Lamp_z1Motor.Color = 'r';
                app.Z1Switch.Value = 'Off';
            end
            
            if app.gantry.MotorState(app.gantry.Z2) == 4194321
                app.Lamp_z2Motor.Color = 'g';
                app.Z2Switch.Value = 'On';
            else
                app.Lamp_z2Motor.Color = 'r';
                app.Z2Switch.Value = 'On';
            end
            
            if app.gantry.IsConnected == 1
                app.XEditField = app.gantry.GetPosition(app.gantry.X);
                app.YEditField = app.gantry.GetPosition(app.gantry.Y);
                app.Z1EditField = app.gantry.GetPosition(app.gantry.Z1);
                app.Z2EditField = app.gantry.GetPosition(app.gantry.Z2);
                app.EditField = app.gantry.GetPosition(app.gantry.U);
            end
            
            
            drawnow limitrate;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            
            if value == 1
                app.ConnectButton.Text == "Disconnect"
            end
            if app.ConnectGantryCheckBox == 1
                app.gantry.Connect;
            end
            if app.ConnectCameraCheckBox == 1
                app.cam.Connect;
            end
            if app.ConnectDispenserCheckBox == 1
                app.dispenser = DISPENSER;
                app.petal = PETALCS(0, fiducial_1, fiducial_2);
                app.gluing = PetalDispensing(app.dispenser,app.gantry,petal);
            end
            
        end
        end

        % Value changed function: USwitch
        function USwitchValueChanged(app, event)
    value = app.USwitch.Value;
    switch value
        case 'On'
            app.gantry.MotorEnable(app.gantry.U)
        case 'Off'
            app.gantry.MotorDisable(app.gantry.U)
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gantry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [-10 100];
            app.ActionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ActionsButtonGroup.Title = 'Actions';
            app.LocatePetalButton = uitogglebutton(app.ActionsButtonGroup);
            app.LocatePetalButton.Text = 'Locate Petal';
            app.LocatePetalButton.Value = true;
            app.LocateHybridsButton = uitogglebutton(app.ActionsButtonGroup);
            app.LocateHybridsButton.Text = 'Locate Hybrids';
            app.GluedispensingButton = uitogglebutton(app.ActionsButtonGroup);
            app.GluedispensingButton.Text = 'Glue dispensing';
            app.HomingButton = uitogglebutton(app.ActionsButtonGroup);
            app.HomingButton.Text = 'Homing';
            app.MovementButtonGroup = uibuttongroup(app.UIFigure);
            app.MovementButtonGroup.Title = 'Movement';
            app.SimulationButton = uiradiobutton(app.MovementButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.ManualButton = uiradiobutton(app.MovementButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.AutoButton = uiradiobutton(app.MovementButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.Button = uiradiobutton(app.MovementButtonGroup);
            app.Button.Text = 'Button';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [-500 500];
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Limits = [-500 500];
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.YEditField.Editable = 'off';
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField.Limits = [-10 100];
            app.Z1EditField.ValueDisplayFormat = '%.3f';
            app.Z1EditField.Editable = 'off';
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField.Limits = [-10 100];
            app.Z2EditField.ValueDisplayFormat = '%.3f';
            app.Z2EditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Motor Current')
            xlabel(app.UIAxes2, {''; ''})
            ylabel(app.UIAxes2, 'mA')
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Ø';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.ConnectGantryCheckBox = uicheckbox(app.UIFigure);
            app.ConnectGantryCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectGantryCheckBoxValueChanged, true);
            app.ConnectGantryCheckBox.Text = 'Connect Gantry';
            app.ConnectDispenserCheckBox = uicheckbox(app.UIFigure);
            app.ConnectDispenserCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectDispenserCheckBoxValueChanged, true);
            app.ConnectDispenserCheckBox.Text = 'Connect Dispenser';
            app.ConnectCameraCheckBox = uicheckbox(app.UIFigure);
            app.ConnectCameraCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectCameraCheckBoxValueChanged, true);
            app.ConnectCameraCheckBox.Text = 'Connect Camera';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.GoButton = uibutton(app.UIFigure, 'state');
            app.GoButton.Text = 'Go';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Limits = [-500 500];
            app.XEditField_2.ValueDisplayFormat = '%.3f';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.Limits = [-500 500];
            app.YEditField_2.ValueDisplayFormat = '%.3f';
            app.Z1EditField_2Label = uilabel(app.UIFigure);
            app.Z1EditField_2Label.Text = 'Z1';
            app.Z1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField_2.Limits = [-10 100];
            app.Z1EditField_2.ValueDisplayFormat = '%.3f';
            app.Z2EditField_2Label = uilabel(app.UIFigure);
            app.Z2EditField_2Label.Text = 'Z2';
            app.GantryMotorsLabel = uilabel(app.UIFigure);
            app.GantryMotorsLabel.Text = 'Gantry Motors';
            app.Z2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField_2.Limits = [-10 100];
            app.Z2EditField_2.ValueDisplayFormat = '%.3f';
            app.Lamp_xMotor = uilamp(app.UIFigure);
            app.Lamp_yMotor = uilamp(app.UIFigure);
            app.Lamp_z1Motor = uilamp(app.UIFigure);
            app.Lamp_z2Motor = uilamp(app.UIFigure);
            app.Lamp_UMotor = uilamp(app.UIFigure);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Ø';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPositionLabel = uilabel(app.UIFigure);
            app.CurrentPositionLabel.Text = 'Current Position';
            app.MoveToLabel = uilabel(app.UIFigure);
            app.MoveToLabel.Text = 'MoveTo';
            app.XSwitchLabel = uilabel(app.UIFigure);
            app.XSwitchLabel.Text = 'X';
            app.XSwitch = uiswitch(app.UIFigure, 'slider');
            app.XSwitch.ValueChangedFcn = createCallbackFcn(app, @XSwitchValueChanged, true);
            app.YSwitchLabel = uilabel(app.UIFigure);
            app.YSwitchLabel.Text = 'Y';
            app.YSwitch = uiswitch(app.UIFigure, 'slider');
            app.YSwitch.ValueChangedFcn = createCallbackFcn(app, @YSwitchValueChanged, true);
            app.Z1SwitchLabel = uilabel(app.UIFigure);
            app.Z1SwitchLabel.Text = 'Z1';
            app.Z1Switch = uiswitch(app.UIFigure, 'slider');
            app.Z1Switch.ValueChangedFcn = createCallbackFcn(app, @Z1SwitchValueChanged, true);
            app.Z2SwitchLabel = uilabel(app.UIFigure);
            app.Z2SwitchLabel.Text = 'Z2';
            app.Z2Switch = uiswitch(app.UIFigure, 'slider');
            app.Z2Switch.ValueChangedFcn = createCallbackFcn(app, @Z2SwitchValueChanged, true);
            app.AllSwitchLabel = uilabel(app.UIFigure);
            app.AllSwitchLabel.Text = 'All';
            app.AllSwitch = uiswitch(app.UIFigure, 'toggle');
            app.AllSwitch.ValueChangedFcn = createCallbackFcn(app, @AllSwitchValueChanged, true);
            app.USwitchLabel = uilabel(app.UIFigure);
            app.USwitchLabel.Text = 'U';
            app.USwitch = uiswitch(app.UIFigure, 'slider');
            app.USwitch.ValueChangedFcn = createCallbackFcn(app, @USwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end